classdef pspm_data_editor_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        fgDataEditor                matlab.ui.Figure
        tbEditNavigation            matlab.ui.container.Toolbar
        tlNavigate                  matlab.ui.container.toolbar.ToggleTool
        tlZoomin                    matlab.ui.container.toolbar.ToggleTool
        tlZoomout                   matlab.ui.container.toolbar.ToggleTool
        tlAddEpoch                  matlab.ui.container.toolbar.ToggleTool
        tlRemoveEpoch               matlab.ui.container.toolbar.ToggleTool
        tlPrevious                  matlab.ui.container.toolbar.PushTool
        tlNext                      matlab.ui.container.toolbar.PushTool
        pnlSettings                 matlab.ui.container.Panel
        pnlEpoch                    matlab.ui.container.Panel
        pbOpenMissingEpochFile      matlab.ui.control.Button
        edOpenMissingEpochFilePath  matlab.ui.control.EditField
        pnlOutput                   matlab.ui.container.Panel
        pbSaveOutput                matlab.ui.control.Button
        pbOpenOutputFile            matlab.ui.control.Button
        edOutputFile                matlab.ui.control.EditField
        pnlInput                    matlab.ui.container.Panel
        edOpenFilePath              matlab.ui.control.EditField
        pbOpenInputFile             matlab.ui.control.Button
        lbChannel                   matlab.ui.control.ListBox
        bgOutputFormat              matlab.ui.container.ButtonGroup
        cbInterpolate               matlab.ui.control.CheckBox
        rbInterpolate               matlab.ui.control.RadioButton
        rbEpochs                    matlab.ui.control.RadioButton
        pbCancel                    matlab.ui.control.Button
        pbApply                     matlab.ui.control.Button
        lbEpochs                    matlab.ui.control.ListBox
        axData                      matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function AddPlot(app, hObject, chan_id, action)
            handles = guidata(hObject);
            
            if isempty(action)
                action = 'replace';
            end;
            
            np = get(handles.axData, 'NextPlot');
            set(handles.axData, 'NextPlot', action);
            corder = get(handles.fgDataEditor, 'defaultAxesColorOrder');
            cl = length(corder)-2;
            
            m = floor((chan_id-0.1)/cl);
            color = corder(chan_id - m*cl, :);
            if strcmpi(handles.input_mode, 'file')
                sr = handles.data{chan_id}.header.sr;
                ydata = handles.data{chan_id}.data;
                xdata = (sr^-1:sr^-1:handles.infos.duration)';
                n_diff =  numel(ydata) - numel(xdata);
                if n_diff < 0
                    xdata = xdata(1:end+n_diff);
                elseif n_diff > 0
                    start_from = xdata(end);
                    xdata(end:end+n_diff) = start_from:sr^-1:(start_from+n_diff*sr^-1);
                end;
            else
                xdata = 1:numel(handles.data)';
                ydata = handles.data;
                sr = 1;
            end;
            
            p = plot(xdata,ydata, 'Color', color);
            set(handles.axData, 'NextPlot', 'add');
            NaN_data = NaN(numel(xdata),1);
            handles.plots{chan_id}.sr = sr;
            handles.plots{chan_id}.data_plot = p;
            handles.plots{chan_id}.y_data = ydata;
            handles.plots{chan_id}.x_data = xdata;
            handles.plots{chan_id}.sel_container = hggroup;
            handles.plots{chan_id}.highlight_plot = plot(xdata,NaN_data, 'LineWidth', 1.5, 'Color', corder(end,:));
            handles.plots{chan_id}.interpolate = plot(xdata,NaN_data, 'LineWidth', 0.5, 'Color', corder(end-1,:), 'LineStyle', '--');
            uistack(handles.plots{chan_id}.interpolate, 'bottom');
            
            % add response plots
            for i=1:numel(handles.epochs)
                rp_x = handles.plots{chan_id}.x_data;
                rp_y = NaN(1,numel(handles.plots{chan_id}.x_data));
                range = rp_x > handles.epochs{i}.range(1) & rp_x < handles.epochs{i}.range(2);
                rp_y(range) = handles.plots{chan_id}.y_data(range);
            
                p = plot(rp_x, rp_y, 'Color', 'green', 'Parent', handles.plots{chan_id}.sel_container);
                handles.epochs{i}.response_plots{chan_id} = struct('p', p);
            end;
            
            set(handles.axData, 'NextPlot', np);
            guidata(hObject, handles);
        end
        
        function sts = CreateOutput(app, hObject)
            
            handles = guidata(hObject);
            
            sts = -1;
            if strcmpi(handles.input_mode, 'file') && strcmpi(handles.output_file, '')
                msgbox('No output file specified. Cannot create output.','Error','error');
            else
                out_file = handles.output_file;
                switch handles.output_type
                    case 'interpolate'
                        % again run interpolation
                        InterpolateData(app, hObject);
                        plots = ~cellfun(@isempty, handles.plots);
                        interp = cellfun(@(x) get(x.interpolate, 'YData'), ...
                            handles.plots(plots), 'UniformOutput', 0);
            
                        if strcmpi(handles.input_mode, 'file')
                            channels = find(plots);
            
                            newd.data = handles.data;
                            newd.infos = handles.infos;
            
                            % replace interpolated data
                            for i=1:numel(channels)
                              newd.data{i}.data = interp{i}';
                            end
                            write_file = 1;
                            write_success = 0;
                            disp_success = 1;
                            if exist(out_file, 'file')
                                button = questdlg(sprintf(['File (%s) already exists. ', ...
                                    'Add channels or replace file?'], out_file), ...
                                    'Add or replace channels?', 'Add channels', ...
                                    'Replace file', 'Cancel', 'Cancel');
                                if strcmpi(button, 'Add channels')
                                    [write_success, ~] = pspm_write_channel(out_file, ...
                                        newd.data(plots), 'add');
                                    write_file = 0;
                                elseif strcmpi(button, 'Cancel')
                                    disp_success = 0;
                                    write_file = 0;
                                end
                            end
            
                            if write_file
                                newd.options.overwrite = 1;
                                [write_success, ~, ~] = pspm_load_data(out_file, newd);
                                if disp_success
                                    if write_success
                                        helpdlg('File successfully written.',...
                                            'File successfully written.');
                                    else
                                        errordlg('Could not write file correctly.',...
                                            'Error while saving file.');
                                    end
                                end
                            end
            
                        else
                            handles.output = interp;
                        end
                        sts = 1;
                    case 'epochs'
                        ep = cellfun(@(x) x.range', handles.epochs, 'UniformOutput', 0);
                        epochs = cell2mat(ep)';
            
                        if strcmpi(handles.input_mode, 'file')
                            if exist(out_file, 'file')
                                button = questdlg(sprintf(...
                                    'File (%s) already exists. Overwrite?', out_file), ...
                                    'Add channels?', 'Yes', 'No', 'No');
                                write_ok = strcmpi(button, 'Yes');
                            else
                                write_ok = true;
                            end
            
                            if write_ok
                                save(out_file, 'epochs');
                            end
                        else
                            handles.output = epochs;
                        end
                        sts = 1;
                    otherwise
                        handles.output = {};
                        sts = 1;
                end
            end
            
            guidata(hObject, handles);
        end
        
        function HighlightEpoch(app, hObject, epId)
            handles = guidata(hObject);
            
            % reset all epochs
            ResetEpochHighlight(app, hObject);
            
            % is there anything to highlight?
            if numel(handles.epochs) > 0
                ep = handles.epochs{epId};
                ep.highlighted = true;
                handles.epochs{epId} = ep;
            
                % highlight epochs
                for i=1:numel(ep.response_plots)
                    if ~isempty(ep.response_plots{i})
                        set(ep.response_plots{i}.p, 'Color', 'black', 'LineWidth', 1.5);
                    end;
                end;
            
                cur_xlim = get(handles.axData, 'xlim');
                start = cur_xlim(1);
                stop = cur_xlim(2);
                x_dist = stop-start;
                new_dist = ep.range(2)-ep.range(1);
            
                dstart = min(handles.x_data);
                dstop = max(handles.x_data);
            
                data_dist = dstop - dstart;
            
                if (x_dist < data_dist)
                    % try to set xlim
                    if (new_dist > x_dist) % zoom out
                        start = ep.range(1);
                        stop = ep.range(2);
                    else
                        % try to center data
                        offset = (x_dist-new_dist)/2;
                        start = ep.range(1) - offset;
                        stop = ep.range(2) + offset;
            
                        if start < dstart
                            start = dstart;
                            stop = dstart+x_dist;
                        elseif stop > dstop
                            stop = dstop;
                            start = stop-x_dist;
                        end;
                    end;
                    set(handles.axData, 'xlim', [start,stop]);
                elseif x_dist >= data_dist
                    start = dstart;
                    stop = dstop;
                    set(handles.axData, 'xlim', [start,stop]);
                end;
            
                x_dist = stop - start;
            
                % try to set ylim
                plots = ~cellfun(@isempty, handles.plots);
                minmax = cellfun(@(x) [max(x.y_data(x.x_data >= ep.range(1)& x.x_data <= ep.range(2))), ...
                    max(x.y_data), min(x.y_data(x.x_data >= ep.range(1)& x.x_data <= ep.range(2))), min(x.y_data)], ...
                    handles.plots(plots), 'UniformOutput', false);
                minmax = cell2mat(minmax);
                to = max(minmax(:,1));
                from = min(minmax(:,3));
                dmax = max(minmax(:,2));
                dmin = min(minmax(:,4));
            
                cur_ylim = get(handles.axData, 'ylim');
                y_dist = cur_ylim(2) - cur_ylim(1);
                new_dist = to - from;
            
                if new_dist ~= y_dist
                    if new_dist > y_dist
                        start = from;
                        stop = to;
                    elseif new_dist < y_dist
                        if data_dist > x_dist
                            offset = (y_dist-new_dist)/2;
                            start = from - offset;
                            stop = to + offset;
                            if (stop > dmax || start < dmin) && ...
                                    (round(dmax-dmin)==round(stop-start))
                                stop = dmax;
                                start = dmin;
                            end;
                        else
                            start = dmin;
                            stop = dmax;
                        end;
                    end;
                    set(handles.axData, 'ylim', [start,stop]);
                end;
            
                handles.highlighted_epoch = epId;
                guidata(hObject, handles);
            end
        end
        
        function InterpolateData(app, hObject)
            handles = guidata(hObject);
            interp_state = get(handles.cbInterpolate, 'Value');
            
            if strcmpi(handles.output_type, 'interpolate')
                for i=1:numel(handles.plots)
                    if ~isempty(handles.plots{i})
                        xd = handles.plots{i}.x_data;
                        yd = handles.plots{i}.y_data;
            
                        for j = 1:numel(handles.epochs)
                            range = xd >= handles.epochs{j}.range(1) & xd <= handles.epochs{j}.range(2);
                            yd(range) = NaN;
                        end;
                        [sts, newyd] = pspm_interpolate(yd);
                        if ~isempty(newyd)
                            set(handles.plots{i}.interpolate, 'YData', newyd);
                        end;
                        if interp_state == 0
                            set(handles.plots{i}.interpolate, 'Visible', 'off')
                        else
                            set(handles.plots{i}.interpolate, 'Visible', 'on')
                        end;
                    end;
                end
            else
                for i=1:numel(handles.plots)
                    if ~isempty(handles.plots{i})
                        NaN_data = NaN(numel(handles.plots{i}.x_data),1);
                        set(handles.plots{i}.interpolate, 'YData', NaN_data);
                    end;
                end;
            end
        end
        
        function PlotData(app, hObject)
            handles = guidata(hObject);
            channel = {};
            % load data
            switch handles.input_mode
                case 'file'
                    % get highest sample rate
                    sr = max(cellfun(@(x) x.header.sr, handles.data));
                    xdata = (0:sr^-1:handles.infos.duration)';
                    chan_id = get(handles.lbChannel, 'Value');
                    if ~any(numel(handles.data) < chan_id)
                        channel = chan_id;
                    else
                        warning('Cannot plot selected channel(s).');
                    end;
                case 'raw'
                    xdata = (1:numel(handles.data))';
                    channel = 1;
            end;
            
            handles.selected_data = NaN(numel(xdata),1);
            handles.x_data = xdata;
            
            guidata(hObject, handles);
            
            if ~isempty(channel)
                np = get(handles.axData, 'NextPlot');
                action = 'replace';
                for i=1:numel(channel)
                    AddPlot(app, hObject, channel(i), action);
                    action = 'add';
                end;
                set(handles.axData, 'NextPlot', np);
            end
        end
        
        function RemovePlot(app, hObject, chan_id)
            handles = guidata(hObject);
            
            if numel(handles.plots) >= chan_id
            
                % remove response plots
                for i=1:numel(handles.epochs)
                    if numel(handles.epochs{i}.response_plots) >= chan_id ...
                            && ~isempty(handles.epochs{i}.response_plots{chan_id})
                        delete(handles.epochs{i}.response_plots{chan_id}.p);
                        handles.epochs{i}.response_plots{chan_id} = [];
                    end;
                end;
            
                delete(handles.plots{chan_id}.data_plot);
                delete(handles.plots{chan_id}.sel_container);
                delete(handles.plots{chan_id}.highlight_plot);
                delete(handles.plots{chan_id}.interpolate);
                % empty entry
                handles.plots{chan_id} = [];
            end;
            
            guidata(hObject, handles);
        end
        
        function ResetEpochHighlight(app, hObject)
            handles = guidata(hObject);
            
            for i=1:numel(handles.epochs)
                if handles.epochs{i}.highlighted
                    for j=1:numel(handles.epochs{i}.response_plots)
                        if ~isempty(handles.epochs{i}.response_plots{j})
                            set(handles.epochs{i}.response_plots{j}.p, ...
                                'Color', 'green',  ...
                                'LineWidth',0.5 ...
                                );
                        end;
                    end;
                    handles.epochs{i}.highlighted = false;
                end;
            end;
            
            guidata(hObject, handles);
        end
        
        function SelectedArea(app, hObject, action)
            handles = guidata(hObject);
            
            if isfield(handles, 'x_data')
                start = handles.select.start;
                if strcmpi(action, 'highlight')
                    pt = get(handles.axData, 'CurrentPoint');
                    stop = pt(1,1:2);
                else
                    stop = handles.select.stop;
                    % turn highlight off
                    for i=1:numel(handles.plots)
                        p = handles.plots{i};
                        if ~isempty(p)
                            xd = p.x_data;
                            highlight_yd = NaN(numel(xd),1);
                            set(p.highlight_plot, 'YData', highlight_yd');
                        end;
                    end;
                end;
            
                if start(1) > stop(1)
                    x_from = stop(1);
                    x_to = start(1);
                else
                    x_from = start(1);
                    x_to = stop(1);
                end;
            
            
                sel_x = handles.x_data;
            
                switch action
                    case 'add'
                        handles.selected_data(sel_x >= x_from & sel_x <= x_to) = 1;
                    case 'remove'
                        handles.selected_data(sel_x >= x_from & sel_x <= x_to) = NaN;
                    case 'highlight'
                        for i=1:numel(handles.plots)
                            p = handles.plots{i};
                            if ~isempty(p)
                                xd = p.x_data;
                                yd = p.y_data;
                                r = xd >= x_from & xd <= x_to;
                                if strcmpi(handles.mode, 'removeepoch')
                                    ep = findSelectedEpochs(app, hObject);
                                    sel_d = zeros(size(xd));
            
                                    for j=1:size(ep,1)
                                        sel_d(xd >= ep(j,1) & xd <= ep(j,2)) = 1;
                                    end;
            
                                    r = r & sel_d;
                                end;
            
                                highlight_yd = NaN(numel(xd),1);
                                highlight_yd(r) = yd(r);
                                set(p.highlight_plot, 'YData', highlight_yd');
                            end;
                        end;
                end;
            
                if ~strcmpi(action, 'highlight')
                    handles.select.start = [0,0];
                    handles.select.stop = [0,0];
                end;
            
                guidata(hObject, handles);
            end
        end
        
        function UpdateEpochList(app, hObject)
            handles = guidata(hObject);
            
            if numel(handles.plots) > 0
                ep = findSelectedEpochs(app, hObject);
                epochs = handles.epochs;
            
                % add epochs if necessary
                for i=1:size(ep,1)
                    response_plots = cell(numel(handles.plots),1);
                    k = 1;
                    epochFound = false;
                    while ~epochFound && k <= numel(epochs)
                        if epochs{k}.range == ep(i,1:2)
                            epochFound = true;
                        end;
                        k = k+1;
                    end;
            
                    % add epoch if not found
                    if ~epochFound
                        hold on;
                        for j=1:numel(handles.plots)
                            if ~isempty(handles.plots{j})
                                rp_y = NaN(1,numel(handles.plots{j}.x_data));
                                rp_x = handles.plots{j}.x_data;
                                sta = ep(i,1);
                                sto = ep(i,2);
                                r = rp_x >= sta & rp_x <= sto;
                                rp_y(r) = handles.plots{j}.y_data(r);
            
            
                                p = plot(rp_x, rp_y, 'Color', 'green', 'Parent', handles.plots{j}.sel_container);
                                response_plots{j} = struct('p', p, 'p_id', j);
                            end;
                        end;
                        hold off;
            
                        epochs{numel(epochs) + 1} = struct( ...
                            'name', sprintf('%d-%d', ep(i,1), ep(i,2)) , ...
                            'range', ep(i, 1:2), ...
                            'highlighted', false, ...
                            'response_plots', {response_plots} ...
                            );
                    end;
                end;
            
                % remove epochs if necessary
                if numel(epochs) ~= size(ep,1)
                    i = 1;
                    while i <= numel(epochs)
                        epochFound = false;
                        k = 1;
                        while ~epochFound && k <= size(ep,1)
                            if epochs{i}.range == ep(k, 1:2)
                                epochFound = true;
                            end;
                            k = k+1;
                        end;
            
                        if ~epochFound
                            for j=1:numel(epochs{i}.response_plots)
                                if ~isempty(epochs{i}.response_plots{j})
                                    delete(epochs{i}.response_plots{j}.p);
                                end;
                            end;
                            epochs(i) = [];
                        else
                            i = i+1;
                        end;
                    end;
                end;
            
            
                names = cellfun(@(x) x.name, epochs, 'UniformOutput', 0);
                sel_ep = get(handles.lbEpochs, 'Value');
                if sel_ep > numel(names)
                    sel_ep = max(numel(names), 1);
                    set(handles.lbEpochs, 'Value', sel_ep);
                elseif (sel_ep == 0) && (numel(names) > 0)
                    sel_ep = 1;
                    handles.highlighted_epoch = -1;
                    set(handles.lbEpochs, 'Value', sel_ep);
                end;
                set(handles.lbEpochs, 'String', names);
            
                handles.epochs = epochs;
                guidata(hObject, handles);
            
                InterpolateData(app, hObject);
            end
        end
        
        function handles = add_epochs(app, hObject, handles)
            E = load(handles.epoch_file, 'epochs');
            epochs = E.epochs';
            % for each ep add an area as if drawn by the user and add to epoch list
            for ep = epochs
                handles.select.start = [ ep(1), 0.5 ];
                handles.select.stop = [ ep(2), 0.5 ];
                handles.select.p = 0;
                guidata(hObject, handles);
                SelectedArea(app, hObject, 'add');
                UpdateEpochList(app, hObject);
            end
        end
        
        function bgOutputFormat_SelectionChangedFcn(app, hObject, eventdata, handles)
            % hObject    handle to the selected object in bgOutputFormat
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if get(handles.rbInterpolate, 'Value')
                set(handles.cbInterpolate, 'Enable', 'on');
                handles.output_type = 'interpolate';
            else
                set(handles.cbInterpolate, 'Enable', 'off');
                handles.output_type = 'epochs';
            end;
            
            guidata(hObject, handles);
            InterpolateData(app, hObject);
        end
        
        function buttonDown_Callback(app, hObject, data)
            % get current cursor position
            handles = guidata(hObject);
            switch handles.mode
                case {'addepoch','removeepoch'}
                    pt = get(handles.axData, 'CurrentPoint');
                    handles.select.start = pt(1,1:2);
            end;
            guidata(hObject, handles);
        end
        
        function buttonMotion_Callback(app, hObject, data)
            handles = guidata(hObject);
            
            if isfield(handles, 'mode')
                switch handles.mode
                    case {'addepoch', 'removeepoch'}
                        if isequal(handles.select.stop,[0,0]) && ...
                                ~isequal(handles.select.start,[0,0])
                            drawSelection(app, hObject);
                            SelectedArea(app, hObject, 'highlight');
                        end;
                end;
            end
        end
        
        function buttonUp_Callback(app, hObject, data)
            % get current cursor position
            handles = guidata(hObject);
            switch handles.mode
                case 'addepoch'
                    pt = get(handles.axData, 'CurrentPoint');
                    handles.select.stop = pt(1,1:2);
                    if handles.select.p ~= 0
                        delete(handles.select.p);
                        handles.select.p = 0;
                    end;
                    % add selected area and draw
                    guidata(hObject, handles);
                    SelectedArea(app, hObject, 'add');
                case 'removeepoch'
                    pt = get(handles.axData, 'CurrentPoint');
                    handles.select.stop = pt(1,1:2);
                    if handles.select.p ~= 0
                        delete(handles.select.p);
                        handles.select.p = 0;
                    end;
                    % add selected area and draw
                    guidata(hObject, handles);
                    SelectedArea(app, hObject, 'remove');
            end;
            
            UpdateEpochList(app, hObject);
        end
        
        function drawSelection(app, hObject)
            handles = guidata(hObject);
            pt = get(handles.axData, 'CurrentPoint');
            pos = pt(1,1:2);
            start = handles.select.start;
            
            x = [start(1), pos(1), pos(1), start(1)];
            y = [start(2), start(2), pos(2), pos(2)];
            
            if handles.select.p ~= 0
                p = handles.select.p;
                set(p, 'XData', x);
                set(p, 'YData', y);
            else
                p = patch(x,y, 'white', 'FaceColor', 'none');
                handles.select.p = p;
            end;
            
            guidata(hObject, handles);
        end
        
        function epochs = findSelectedEpochs(app, hObject)
            handles = guidata(hObject);
            
            sd = handles.selected_data;
            v_pos = find(~isnan(sd));
            xd = handles.x_data;
            if numel(v_pos)>=1
                epoch_end = xd([v_pos(find(diff(v_pos) > 1)); v_pos(end)]);
                epoch_start = xd(v_pos([1;find(diff(v_pos) > 1)+1]));
            
                epochs = [epoch_start, epoch_end];
            else
                epochs = [];
            end
        end
        
        function loadFromFile(app, hObject, file)
            handles = guidata(hObject);
            
            % clear epochs
            handles.selected_data(:) = NaN;
            guidata(hObject, handles);
            
            UpdateEpochList(app, hObject);
            handles = guidata(hObject);
            
            % clear channels
            handles.channels = {};
            set(handles.lbChannel, 'String', '');
            
            % remove plots
            for i=1:numel(handles.plots)
                if ~isempty(handles.plots{i})
                    RemovePlot(app, hObject, i);
                end;
            end;
            
            % load file
            [sts, infos, data] = pspm_load_data(file);
            channels = cellfun(@(x) {x.header.chantype,x.header.units}, data, 'UniformOutput', 0);
            
            set(handles.edOpenFilePath, 'String', file);
            
            % format channels
            corder = get(handles.fgDataEditor, 'defaultAxesColorOrder');
            cl = length(corder)-2;
            disp_names = cell(numel(channels), 1);
            for i=1:numel(channels)
                if strcmpi(channels{i}(2), 'events')
                    disp_names{i} = sprintf('<html><font color="#DDDDDD">%s</font></html>', channels{i}{1});
                else
                    m = floor((i-0.1)/cl);
                    color = corder(i - m*cl, :);
                    disp_names{i} = sprintf('<html><font bgcolor="#%02s%02s%02s">%s</font></html>',...
                        dec2hex(round(color(1)*255)), ...
                        dec2hex(round(color(2)*255)), ...
                        dec2hex(round(color(3)*255)), ...
                        channels{i}{1});
                end;
            end;
            
            set(handles.lbChannel, 'String', disp_names);
            
            handles.data = data;
            handles.infos = infos;
            handles.input_mode = 'file';
            handles.plots = cell(size(data));
            guidata(hObject, handles);
            
            PlotData(app, hObject);
        end
        
        function ppOutput_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to ppOutput (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function tlCursor_ClickedCallback(app, hObject, eventdata, handles)
            % hObject    handle to tlCursor (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            set(gcf, 'Pointer', 'arrow');
        end
        
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.tlNavigate, app.tlZoomin, app.tlZoomout];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.fgDataEditor, 'off')
            rotate3d(app.fgDataEditor, 'off');
            pan(app.fgDataEditor, 'off');
            zoom(app.fgDataEditor,'off');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function pspm_data_editor_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to pspm_data_editor (see VARARGIN)
            
            % initialise
            % -------------------------------------------------------------------------
            global settings;
            if isempty(settings), pspm_init; end;
            
            if get(handles.rbInterpolate, 'Value')
                set(handles.cbInterpolate, 'Enable', 'on');
                handles.output_type = 'interpolate';
            else
                set(handles.cbInterpolate, 'Enable', 'off');
                handles.output_type = 'epochs';
            end;
            
            % Choose default command line output for pspm_data_editor
            handles.output = {};
            handles.mode = 'default';
            handles.select = struct();
            handles.plots = {};
            handles.selected_data = [];
            handles.epochs = {};
            handles.highlighted_epoch = -1;
            
            handles.data = {};
            handles.input_mode = '';
            handles.input_file = '';
            handles.output_file = '';
            
            set(handles.fgDataEditor, 'WindowButtonDownFcn', @app.buttonDown_Callback);
            set(handles.fgDataEditor, 'WindowButtonUpFcn', @app.buttonUp_Callback);
            set(handles.fgDataEditor, 'WindowButtonMotionFcn', @app.buttonMotion_Callback);
            
            % load options
            if numel(varargin) > 1 && isstruct(varargin{2})
                handles.options = varargin{2};
            
                % check if options are valid and assign accordingly
                if isfield(handles.options, 'output_file') && ...
                    ischar(handles.options.output_file)
            
                    handles.output_file = handles.options.output_file;
                    set(handles.edOutputFile, 'String', handles.output_file);
                end
            
                if isfield(handles.options, 'epoch_file') && ...
                    ischar(handles.options.epoch_file)
            
                    handles.epoch_file = handles.options.epoch_file;
                    set(handles.edOpenMissingEpochFilePath, 'String', handles.epoch_file);
                end
            
            end;
            
            % Update handles structure
            guidata(hObject, handles);
            if numel(varargin) > 0
                if ischar(varargin{1})
                    if exist(varargin{1}, 'file')
                        set(handles.pnlInput, 'Visible', 'on');
                        set(handles.pnlOutput, 'Visible', 'on');
            
                        loadFromFile(app, hObject, varargin{1});
                    else
                        warning('File ''%s'' does not exist.', varargin{1});
                    end;
                elseif isnumeric(varargin{1})
                    set(handles.pnlInput, 'Visible', 'off');
            
                    handles.data = varargin{1};
                    handles.input_mode = 'raw';
                    guidata(hObject, handles);
                    PlotData(app, hObject);
            
                    if isfield(handles, 'options') && isfield(handles.options, 'output_file')
                        set(handles.pnlOutput, 'Visible', 'off');
                    end
                    if isfield(handles, 'options') && isfield(handles.options, 'epoch_file')
                        set(handles.pnlEpoch, 'Visible', 'off');
                        handles = guidata(hObject);
                        add_epochs(app, hObject, handles)
                    end
            
                end;
            end;
            uiwait(handles.fgDataEditor);
        end

        % Value changed function: cbInterpolate
        function cbInterpolate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cbInterpolate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of cbInterpolate
            InterpolateData(app, hObject);
        end

        % Value changed function: edOpenFilePath
        function edOpenFilePath_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edOpenFilePath (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edOpenFilePath as text
            %        str2double(get(hObject,'String')) returns contents of edOpenFilePath as a double
            if isempty(handles.input_file)
                set(hObject, 'String', 'No input specified');
            else
                set(hObject, 'String', handles.input_file);
            end
        end

        % Value changed function: edOpenMissingEpochFilePath
        function edOpenMissingEpochFilePath_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edOpenMissingEpochFilePath (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edOpenMissingEpochFilePath as text
            %        str2double(get(hObject,'String')) returns contents of edOpenMissingEpochFilePath as a double
            if isempty(handles.epoch_file)
                set(hObject, 'String', 'No input specified');
            else
                set(hObject, 'String', handles.epoch_file);
            end
        end

        % Value changed function: edOutputFile
        function edOutputFile_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edOutputFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edOutputFile as text
            %        str2double(get(hObject,'String')) returns contents of edOutputFile as a double
            if isempty(handles.output_file)
                set(hObject, 'String', 'No output specified');
            else
                set(hObject, 'String', handles.output_file);
            end
        end

        % Close request function: fgDataEditor
        function fgDataEditor_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to fgDataEditor (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: delete(hObject) closes the figure
            handles.output = {};
            uiresume(handles.fgDataEditor);
        end

        % Value changed function: lbChannel
        function lbChannel_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to lbChannel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns lbChannel contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from lbChannel
            
            if strcmpi(handles.input_mode, 'file')
                plots = find(cellfun(@(x) ~isempty(x), handles.plots));
                sel = get(hObject, 'Value');
                to_plot = sel(~ismember(sel, plots));
                to_remove = plots(~ismember(plots, sel));
            
                for i=1:numel(to_remove)
                    RemovePlot(app, hObject, to_remove(i));
                end;
            
                for i=1:numel(to_plot)
                    if ~strcmpi(handles.data{to_plot(i)}.header.units, 'events')
                        AddPlot(app, hObject, to_plot(i), 'add');
                    end;
                end;
                InterpolateData(app, hObject);
            end
        end

        % Value changed function: lbEpochs
        function lbEpochs_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to lbEpochs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns lbEpochs contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from lbEpochs
            
            epId = get(hObject,'Value');
            HighlightEpoch(app, hObject, epId);
        end

        % Button pushed function: pbApply
        function pbApply_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbApply (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if CreateOutput(app, hObject) == 1
                uiresume(handles.fgDataEditor);
            end
        end

        % Button pushed function: pbCancel
        function pbCancel_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbCancel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            handles.output = {};
            guidata(hObject, handles);
            uiresume(handles.fgDataEditor);
        end

        % Button pushed function: pbOpenInputFile
        function pbOpenInputFile_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbOpenInputFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            [file, path] = uigetfile('*.mat', 'Select input file');
            if file ~= 0
                fn = [path,file];
                handles.input_file = fn;
                guidata(hObject, handles);
                loadFromFile(app, hObject, fn);
            end
        end

        % Button pushed function: pbOpenMissingEpochFile
        function pbOpenMissingEpochFile_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbOpenMissingEpochFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [file, path] = uigetfile('*.mat', 'Select missing epoch file');
            if file ~= 0
                handles.epoch_file = [ path, file ];
                add_epochs(app, hObject, handles);
            end
        end

        % Button pushed function: pbOpenOutputFile
        function pbOpenOutputFile_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbOpenOutputFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            [file, path] = uiputfile('*.mat', 'Select output file');
            if file ~= 0
                fn = [path,file];
                handles.output_file = fn;
                guidata(hObject, handles);
                set(handles.edOutputFile, 'String', handles.output_file);
            end
        end

        % Button pushed function: pbSaveOutput
        function pbSaveOutput_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbSaveOutput (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            CreateOutput(app, hObject);
        end

        % Off callback: tlAddEpoch
        function tlAddEpoch_OffCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tlAddEpoch (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            handles.mode = 'default';
            
            % change to crosshair
            set(gcf,'Pointer','arrow');
            
            handles.select.start = [0,0];
            handles.select.stop = [0,0];
            handles.select.p = 0;
            
            guidata(hObject, handles);
        end

        % On callback: tlAddEpoch
        function tlAddEpoch_OnCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tlAddEpoch (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            set(handles.tlRemoveEpoch, 'State', 'off');
            set(handles.tlZoomin, 'State', 'off');
            set(handles.tlZoomout, 'State', 'off');
            set(handles.tlNavigate, 'State', 'off');
            pan off;
            zoom off;
            
            handles.mode = 'addepoch';
            
            % change to crosshair
            set(gcf,'Pointer','crosshair');
            
            handles.select.start = [0,0];
            handles.select.stop = [0,0];
            handles.select.p = 0;
            guidata(hObject, handles);
        end

        % Off callback: tlNavigate
        function tlNavigate_OffCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tlNavigate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            pan off;
        end

        % On callback: tlNavigate
        function tlNavigate_OnCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tlNavigate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.tlRemoveEpoch, 'State', 'off');
            set(handles.tlZoomin, 'State', 'off');
            set(handles.tlZoomout, 'State', 'off');
            set(handles.tlAddEpoch, 'State', 'off');
            zoom off;
            pan on;
        end

        % Clicked callback: tlNext
        function tlNext_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tlNext (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            if handles.highlighted_epoch == -1 || ...
                    handles.highlighted_epoch >= numel(handles.epochs)
                new_ep = 1;
            else
                new_ep = handles.highlighted_epoch + 1;
            end;
            
            set(handles.lbEpochs, 'Value', new_ep);
            HighlightEpoch(app, hObject, new_ep);
        end

        % Clicked callback: tlPrevious
        function tlPrevious_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tlPrevious (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if handles.highlighted_epoch == -1 || ...
                    handles.highlighted_epoch == 1 || ...
                    handles.highlighted_epoch > numel(handles.epochs)
            
                new_ep = numel(handles.epochs);
            else
                new_ep = handles.highlighted_epoch - 1;
            end;
            set(handles.lbEpochs, 'Value', new_ep);
            HighlightEpoch(app, hObject, new_ep);
        end

        % Off callback: tlRemoveEpoch
        function tlRemoveEpoch_OffCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tlRemoveEpoch (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            handles.mode = 'default';
            set(gcf,'Pointer','arrow');
            guidata(hObject, handles);
        end

        % On callback: tlRemoveEpoch
        function tlRemoveEpoch_OnCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tlRemoveEpoch (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            set(handles.tlAddEpoch, 'State', 'off');
            set(handles.tlZoomin, 'State', 'off');
            set(handles.tlZoomout, 'State', 'off');
            set(handles.tlNavigate, 'State', 'off');
            pan off;
            zoom off;
            
            handles.mode = 'removeepoch';
            
            % change to crosshair
            set(gcf,'Pointer','crosshair');
            
            handles.select.start = [0,0];
            handles.select.stop = [0,0];
            handles.select.p = 0;
            guidata(hObject, handles);
        end

        % On callback: tlZoomin
        function tlZoomin_OnCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tlZoomin (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            set(handles.tlRemoveEpoch, 'State', 'off');
            set(handles.tlAddEpoch, 'State', 'off');
            set(handles.tlZoomout, 'State', 'off');
            set(handles.tlNavigate, 'State', 'off');
            pan off;
            
            z = zoom;
            set(z, 'Motion', 'horizontal');
            set(z, 'Direction', 'in');
            % unset OnCallback while enabling zoomin otherwise this would end up in a
            % recurstion loop (dont know why exactly)
            cb = get(handles.tlZoomin, 'OnCallback');
            set(handles.tlZoomin, 'OnCallback', '');
            set(z, 'Enable', 'on');
            set(handles.tlZoomin, 'OnCallback', cb);
        end

        % On callback: tlZoomout
        function tlZoomout_OnCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to tlZoomout (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.tlRemoveEpoch, 'State', 'off');
            set(handles.tlZoomin, 'State', 'off');
            set(handles.tlAddEpoch, 'State', 'off');
            set(handles.tlNavigate, 'State', 'off');
            pan off;
            
            z = zoom;
            set(z, 'Motion', 'horizontal');
            set(z, 'Direction', 'out');
            set(z, 'Enable', 'on');
        end

        % Clicked callback: tlZoomout
        function tlZoomout_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.tlZoomout.State;
            zoomModeObject = zoom(app.fgDataEditor);
            if state
                zoomModeObject.Direction = 'out';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Clicked callback: tlZoomin
        function tlZoomin_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.tlZoomin.State;
            zoomModeObject = zoom(app.fgDataEditor);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Clicked callback: tlNavigate
        function tlNavigate_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable pan based on the
            % tool's current state.
            state = app.tlNavigate.State;
            pan(app.fgDataEditor, char(state));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.fgDataEditor = uifigure('Visible', 'off');
            app.fgDataEditor.Name = 'Data Editor';
            app.fgDataEditor.CloseRequestFcn = createCallbackFcn(app, @fgDataEditor_CloseRequestFcn, true);
            app.fgDataEditor.Tag = 'fgDataEditor';
            app.tbEditNavigation = uitoolbar(app.fgDataEditor);
            app.tbEditNavigation.Tag = 'tbEditNavigation';
            app.tlNavigate = uitoggletool(app.tbEditNavigation);
            app.tlNavigate.Tag = 'tlNavigate';
            app.tlNavigate.Tooltip = 'Pan';
            app.tlNavigate.ClickedCallback = createCallbackFcn(app, @tlNavigate_ClickedCallback, true);
            app.tlNavigate.Icon = 'tlNavigate_image.png';
            app.tlNavigate.OffCallback = createCallbackFcn(app, @tlNavigate_OffCallback, true);
            app.tlNavigate.OnCallback = createCallbackFcn(app, @tlNavigate_OnCallback, true);
            app.tlZoomin = uitoggletool(app.tbEditNavigation);
            app.tlZoomin.Tag = 'tlZoomin';
            app.tlZoomin.Tooltip = 'Zoom In';
            app.tlZoomin.ClickedCallback = createCallbackFcn(app, @tlZoomin_ClickedCallback, true);
            app.tlZoomin.Icon = 'tlZoomin_image.png';
            app.tlZoomin.OnCallback = createCallbackFcn(app, @tlZoomin_OnCallback, true);
            app.tlZoomout = uitoggletool(app.tbEditNavigation);
            app.tlZoomout.Tag = 'tlZoomout';
            app.tlZoomout.Tooltip = 'Zoom Out';
            app.tlZoomout.ClickedCallback = createCallbackFcn(app, @tlZoomout_ClickedCallback, true);
            app.tlZoomout.Icon = 'tlZoomout_image.png';
            app.tlZoomout.OnCallback = createCallbackFcn(app, @tlZoomout_OnCallback, true);
            app.tlAddEpoch = uitoggletool(app.tbEditNavigation);
            app.tlAddEpoch.Tag = 'tlAddEpoch';
            app.tlAddEpoch.Tooltip = 'Add Epoch';
            app.tlAddEpoch.Icon = 'tlAddEpoch_image.png';
            app.tlAddEpoch.Separator = 'on';
            app.tlAddEpoch.OffCallback = createCallbackFcn(app, @tlAddEpoch_OffCallback, true);
            app.tlAddEpoch.OnCallback = createCallbackFcn(app, @tlAddEpoch_OnCallback, true);
            app.tlRemoveEpoch = uitoggletool(app.tbEditNavigation);
            app.tlRemoveEpoch.Tag = 'tlRemoveEpoch';
            app.tlRemoveEpoch.Tooltip = 'Remove Epoch';
            app.tlRemoveEpoch.Icon = 'tlRemoveEpoch_image.png';
            app.tlRemoveEpoch.OffCallback = createCallbackFcn(app, @tlRemoveEpoch_OffCallback, true);
            app.tlRemoveEpoch.OnCallback = createCallbackFcn(app, @tlRemoveEpoch_OnCallback, true);
            app.tlPrevious = uipushtool(app.tbEditNavigation);
            app.tlPrevious.Tag = 'tlPrevious';
            app.tlPrevious.Tooltip = 'Previous Epoch';
            app.tlPrevious.ClickedCallback = createCallbackFcn(app, @tlPrevious_ClickedCallback, true);
            app.tlPrevious.Icon = 'tlPrevious_image.png';
            app.tlPrevious.Separator = 'on';
            app.tlNext = uipushtool(app.tbEditNavigation);
            app.tlNext.Tag = 'tlNext';
            app.tlNext.Tooltip = 'Next Epoch';
            app.tlNext.ClickedCallback = createCallbackFcn(app, @tlNext_ClickedCallback, true);
            app.tlNext.Icon = 'tlNext_image.png';
            app.axData = uiaxes(app.fgDataEditor);
            app.axData.XLim = [0 1];
            app.axData.YLim = [0 1];
            app.axData.ZLim = [0 1];
            app.axData.CLim = [0 1];
            app.axData.ALim = [0 1];
            app.axData.Tag = 'axData';
            app.lbEpochs = uilistbox(app.fgDataEditor);
            app.lbEpochs.Items = {};
            app.lbEpochs.ValueChangedFcn = createCallbackFcn(app, @lbEpochs_Callback, true);
            app.lbEpochs.Tag = 'lbEpochs';
            app.lbEpochs.Value = {};
            app.pbApply = uibutton(app.fgDataEditor, 'push');
            app.pbApply.ButtonPushedFcn = createCallbackFcn(app, @pbApply_Callback, true);
            app.pbApply.Tag = 'pbApply';
            app.pbApply.Text = 'Apply';
            app.pbCancel = uibutton(app.fgDataEditor, 'push');
            app.pbCancel.ButtonPushedFcn = createCallbackFcn(app, @pbCancel_Callback, true);
            app.pbCancel.Tag = 'pbCancel';
            app.pbCancel.Text = 'Cancel';
            app.pnlSettings = uipanel(app.fgDataEditor);
            app.pnlSettings.Title = 'Settings';
            app.pnlSettings.Tag = 'pnlSettings';
            app.bgOutputFormat = uibuttongroup(app.pnlSettings);
            app.bgOutputFormat.Title = 'Output format';
            app.bgOutputFormat.Tag = 'bgOutputFormat';
            app.rbEpochs = uiradiobutton(app.bgOutputFormat);
            app.rbEpochs.Tag = 'rbEpochs';
            app.rbEpochs.Text = 'Epochs';
            app.rbEpochs.Value = true;
            app.rbInterpolate = uiradiobutton(app.bgOutputFormat);
            app.rbInterpolate.Tag = 'rbInterpolate';
            app.rbInterpolate.Text = 'Interpolated data';
            app.cbInterpolate = uicheckbox(app.bgOutputFormat);
            app.cbInterpolate.ValueChangedFcn = createCallbackFcn(app, @cbInterpolate_Callback, true);
            app.cbInterpolate.Tag = 'cbInterpolate';
            app.cbInterpolate.Text = 'Display interpolated curve';
            app.pnlInput = uipanel(app.pnlSettings);
            app.pnlInput.Title = 'Input';
            app.pnlInput.Tag = 'pnlInput';
            app.lbChannel = uilistbox(app.pnlInput);
            app.lbChannel.Items = {};
            app.lbChannel.Multiselect = 'on';
            app.lbChannel.ValueChangedFcn = createCallbackFcn(app, @lbChannel_Callback, true);
            app.lbChannel.Tag = 'lbChannel';
            app.lbChannel.Value = {};
            app.pbOpenInputFile = uibutton(app.pnlInput, 'push');
            app.pbOpenInputFile.ButtonPushedFcn = createCallbackFcn(app, @pbOpenInputFile_Callback, true);
            app.pbOpenInputFile.Tag = 'pbOpenInputFile';
            app.pbOpenInputFile.Text = 'Open input file';
            app.edOpenFilePath = uieditfield(app.pnlInput, 'text');
            app.edOpenFilePath.ValueChangedFcn = createCallbackFcn(app, @edOpenFilePath_Callback, true);
            app.edOpenFilePath.Tag = 'edOpenFilePath';
            app.edOpenFilePath.Value = 'No input specified';
            app.pnlOutput = uipanel(app.pnlSettings);
            app.pnlOutput.Title = 'Output';
            app.pnlOutput.Tag = 'pnlOutput';
            app.edOutputFile = uieditfield(app.pnlOutput, 'text');
            app.edOutputFile.ValueChangedFcn = createCallbackFcn(app, @edOutputFile_Callback, true);
            app.edOutputFile.Tag = 'edOutputFile';
            app.edOutputFile.Value = 'No output specified';
            app.pbOpenOutputFile = uibutton(app.pnlOutput, 'push');
            app.pbOpenOutputFile.ButtonPushedFcn = createCallbackFcn(app, @pbOpenOutputFile_Callback, true);
            app.pbOpenOutputFile.Tag = 'pbOpenOutputFile';
            app.pbOpenOutputFile.Text = 'Open output file';
            app.pbSaveOutput = uibutton(app.pnlOutput, 'push');
            app.pbSaveOutput.ButtonPushedFcn = createCallbackFcn(app, @pbSaveOutput_Callback, true);
            app.pbSaveOutput.Tag = 'pbSaveOutput';
            app.pbSaveOutput.Text = 'Save file';
            app.pnlEpoch = uipanel(app.pnlSettings);
            app.pnlEpoch.Title = 'Epoch Input';
            app.pnlEpoch.Tag = 'pnlEpoch';
            app.edOpenMissingEpochFilePath = uieditfield(app.pnlEpoch, 'text');
            app.edOpenMissingEpochFilePath.ValueChangedFcn = createCallbackFcn(app, @edOpenMissingEpochFilePath_Callback, true);
            app.edOpenMissingEpochFilePath.Tag = 'edOpenMissingEpochFilePath';
            app.edOpenMissingEpochFilePath.Value = 'Missing Epoch File (Optional)';
            app.pbOpenMissingEpochFile = uibutton(app.pnlEpoch, 'push');
            app.pbOpenMissingEpochFile.ButtonPushedFcn = createCallbackFcn(app, @pbOpenMissingEpochFile_Callback, true);
            app.pbOpenMissingEpochFile.Tag = 'pbOpenMissingEpochFile';
            app.pbOpenMissingEpochFile.Text = 'Open Epoch file';
            % Show the figure after all components are created
            app.fgDataEditor.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pspm_data_editor_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.fgDataEditor)

                % Execute the startup function
                runStartupFcn(app, @(app)pspm_data_editor_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.fgDataEditor)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.fgDataEditor)
        end
    end
end