classdef pspm_review_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        pspm_review        matlab.ui.Figure
        panelModel         matlab.ui.container.Panel
        buttonRemoveModel  matlab.ui.control.Button
        buttonAddModel     matlab.ui.control.Button
        listModel          matlab.ui.control.ListBox
        buttonExit         matlab.ui.control.Button
        panelStatus        matlab.ui.container.Panel
        textStatus         matlab.ui.control.Label
        panelPlot          matlab.ui.container.ButtonGroup
        textPlot6          matlab.ui.control.Label
        buttonPlot6        matlab.ui.control.Button
        editEpochNr        matlab.ui.control.EditField
        buttonPlot2        matlab.ui.control.Button
        editSessionNr      matlab.ui.control.EditField
        textSessionRange   matlab.ui.control.Label
        textSessionNr      matlab.ui.control.Label
        editTrialNr        matlab.ui.control.EditField
        buttonPlotClose    matlab.ui.control.Button
        textPlot5          matlab.ui.control.Label
        textPlot4          matlab.ui.control.Label
        textPlot3          matlab.ui.control.Label
        textPlot2          matlab.ui.control.Label
        textPlot1          matlab.ui.control.Label
        buttonPlot5        matlab.ui.control.Button
        buttonPlot4        matlab.ui.control.Button
        buttonPlot3        matlab.ui.control.Button
        buttonPlot1        matlab.ui.control.Button
    end

    
    methods (Access = private)
        function sessionNr = checkSessionNr(app, handles)
            sessionNr = str2num(get(handles.editSessionNr,'String'));
            if isempty(sessionNr) || sessionNr > handles.modelData{handles.currentModel}.maxSessionNr || sessionNr < 1
                uiwait(msgbox(sprintf('Session number has to be within the range [1 - %d]',handles.modelData{handles.currentModel}.maxSessionNr),'Warning'));
                sessionNr = [];
            end
        end
        
        function closeFigures(app, handles)
            close(setdiff(findobj('type','figure'),handles.figToKeep));
        end
        
        function setButtonDisable(app, handles)
            for i=1:handles.nrPlot
                eval(sprintf('set(handles.buttonPlot%d,''Enable'',''off'')',i));
            end
        end
        
        function setButtonEnable(app, handles)
            for i=1:handles.nrPlot
                eval(sprintf('set(handles.buttonPlot%d,''Enable'',''on'')',i));
            end
        end
        
        function setButtonPlotString(app, handles, inString)
            for i=1:size(inString,2)
                if ~isempty(inString{i})
                    eval(sprintf('set(handles.buttonPlot%d,''String'',inString{%d})',i,i));
                    eval(sprintf('set(handles.buttonPlot%d,''Visible'',''on'')',i));
                end
            end
            set(handles.buttonPlotClose,'Visible','on');
        end
        
        function setEpoch(app, handles)
            set(handles.editEpochNr,'Visible','on');
        end
        
        function fig = setFigureHandle(app, handles)
            for i=1:handles.nrPlot
                fig(i).h=-1;
            end
        end
        
        function setInvisble(app, handles)
            for i=1:handles.nrPlot
                eval(sprintf('set(handles.buttonPlot%d,''Visible'',''off'')',i));
                eval(sprintf('set(handles.textPlot%d,''Visible'',''off'')',i));
            end
            set(handles.buttonPlotClose,'Visible','off');
            set(handles.editTrialNr,'Visible','off');
            set(handles.editEpochNr,'Visible','off');
            set(handles.editSessionNr,'Visible','off');
            set(handles.textSessionNr,'Visible','off');
            set(handles.textSessionRange,'Visible','off');
        end
        
        function setSession(app, handles)
            nr = handles.modelData{handles.currentModel}.maxSessionNr;
            set(handles.editSessionNr,'Visible','on');
            set(handles.textSessionNr,'Visible','on');
            set(handles.textSessionRange,'Visible','on');
            set(handles.textSessionRange,'String',sprintf('[1 - %d]',nr));
        end
        
        function setTextPlotString(app, handles, inString)
            for i=1:size(inString,2)
                if ~isempty(inString{i})
                    eval(sprintf('set(handles.textPlot%d,''String'',inString{%d})',i,i));
                    eval(sprintf('set(handles.textPlot%d,''Visible'',''on'')',i));
                end
            end
        end
        
        function setTrial(app, handles)
            set(handles.editTrialNr,'Visible','on');
        end
        
        function showModel(app, handles)
            
            % detect model
            switch handles.modelData{handles.currentModel}.modeltype
                case 'glm'
                    buttonPlotString = {'Plot', ...
                        'Plot', ...
                        'Plot', ...
                        'Show', ...
                        'Plot'};
                    textPlotString = {'Design matrix in SPM style', ...
                        'Orthogonality in SPM style', ...
                        'Predicted & observed', ...
                        'Regressors in command window', ...
                        'Reconstructed responses'};
                    % detect contrasts
                    if isfield(handles.modelData{handles.currentModel}.model, 'con')
                        buttonPlotString{6} = 'Show';
                        textPlotString{6} = 'Contrast names in command window';
                    end;
                    setInvisble(app, handles);
                    setButtonPlotString(app, handles, buttonPlotString);
                    setTextPlotString(app, handles, textPlotString);
                    set(handles.textStatus,'String','Detected modeltype: GLM');
                    drawnow
            
                case 'dcm'
                    buttonPlotString = {'Display', ...
                        'Display', ...
                        'Display', ...
                        'Show'};
                    textPlotString = {'All trials for one session', ...
                        'Diagnostics for trial nr.', ...
                        'Skin conductance response function (SCR)', ...
                        'Trial and condition names in command window'};
                    % detect contrasts
                    if isfield(handles.modelData{handles.currentModel}.model, 'con')
                        buttonPlotString{5} = 'Show';
                        textPlotString{5} = 'Contrast names in command window';
                    end;
                    setInvisble(app, handles);
                    setButtonPlotString(app, handles, buttonPlotString);
                    setTextPlotString(app, handles, textPlotString);
                    setSession(app, handles);
                    setTrial(app, handles);
                    set(handles.textStatus,'String','Detected modeltype: DCM')
                    drawnow
            
                case 'sf'
                    buttonPlotString = {'Display'};
                    textPlotString = {'Diagnostics for epoch nr.'};
                    if isfield(handles.modelData{handles.currentModel}.model, 'con')
                        buttonPlotString{2} = 'Show';
                        textPlotString{2} = 'Contrast names in command window';
                    end;
                    setInvisble(app, handles);
                    setButtonPlotString(app, handles, buttonPlotString);
                    setTextPlotString(app, handles, textPlotString);
                    setEpoch(app, handles);
                    set(handles.textStatus,'String','Detected modeltype: SF')
                    drawnow
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function pspm_review_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to pspm_review (see VARARGIN)
            
            % Left alignment of text fields
            set(handles.textPlot1,'HorizontalAlignment','left')
            set(handles.textPlot2,'HorizontalAlignment','left')
            set(handles.textPlot3,'HorizontalAlignment','left')
            set(handles.textPlot4,'HorizontalAlignment','left')
            set(handles.textPlot5,'HorizontalAlignment','left')
            set(handles.textPlot6,'HorizontalAlignment','left')
            set(handles.textStatus,'HorizontalAlignment','left')
            set(handles.textStatus,'String','Select a model...');
            
            handles.nrPlot = 6;
            %handles.figCnt = 0;
            handles.modelCnt = 0;
            handles.currentModel = 1;
            handles.listModelEntry{1} = '';
            
            % buffer figure handles to keep
            handles.figToKeep = findall(0, 'type', 'figure');
            
            % Choose default command line output for pspm_review
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: buttonAddModel
        function buttonAddModel_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to buttonAddModel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filt='.*\.mat$';
            [modelfileArray, sts]=spm_select([1 inf], filt, 'Select model file');
            if sts==0, return; end;
            
            % check each model file
            setButtonDisable(app, handles)
            set(handles.textStatus,'String','Loading model. Please wait...');
            drawnow
            for iFile = 1:size(modelfileArray, 1)
                modelfile = spm_file(modelfileArray(iFile, :));
                [sts, model, modeltype] = pspm_load1(modelfile, 'all', 'any');
                if sts == -1 || ~any(strcmp(modeltype,{'glm','dcm','sf'}))
                    set(handles.textStatus,'String','No supported modeltype detected');
                    drawnow
                    return;
                elseif strcmp(modeltype,'sf')
                    dcm = cellfun(@(field) strcmpi(field(1).modeltype, 'dcm'), model.model);
                    if ~any(dcm)
                        set(handles.textStatus,'String','No supported modeltype detected');
                        drawnow
                        return;
                    end
                end
            
                handles.modelCnt = handles.modelCnt+1;
            
                handles.modelData{handles.modelCnt}.modeltype = modeltype;
                handles.modelData{handles.modelCnt}.model = model;
                handles.modelData{handles.modelCnt}.modelfile = modelfile;
                if strcmp(modeltype,'dcm')
                    handles.modelData{handles.modelCnt}.maxSessionNr = numel(model.sn);
                end
                if strcmp(modeltype,'sf')
                    handles.modelData{handles.modelCnt}.maxEpochNr = numel(model.model{dcm});
                end
                [~,modelfileName,~] = fileparts(modelfile);
                handles.listModelEntry{handles.modelCnt} = modelfileName;
                set(handles.listModel, 'String', handles.listModelEntry);
                set(handles.listModel, 'Value', handles.modelCnt);
                handles.currentModel = handles.modelCnt;
                handles.modelData{handles.modelCnt}.fig = setFigureHandle(app, handles);
            
                showModel(app, handles);
            
            end;
            setButtonEnable(app, handles)
            
            if handles.modelCnt > 1
                set(handles.buttonRemoveModel, 'Enable', 'on');
            end
            guidata(hObject, handles);
        end

        % Button pushed function: buttonExit
        function buttonExit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to buttonExit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            closeFigures(app, handles);
            delete(gcbf)
        end

        % Button pushed function: buttonPlot1
        function buttonPlot1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to buttonPlot1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            tmpStatusString = get(handles.textStatus,'String');
            set(handles.textStatus,'String','Plotting is in progress. Please wait...');
            switch handles.modelData{handles.currentModel}.modeltype
                case 'glm'
                    handles.modelData{handles.currentModel}.fig = pspm_rev_glm(handles.modelData{handles.currentModel}.modelfile, handles.modelData{handles.currentModel}.model, 1);
            
                case 'dcm'
                    sessionNr = checkSessionNr(app, handles);
                    if sessionNr
                        pspm_rev_dcm(handles.modelData{handles.currentModel}.model, 'sum', sessionNr, [])
                    end
            
                case 'sf'
                    epochNr = str2num(get(handles.editEpochNr,'String'));
                    if isempty(epochNr) || epochNr > handles.modelData{handles.currentModel}.maxEpochNr || epochNr < 1
                        uiwait(msgbox(sprintf('Epoch number has to be within the range [1 - %d]',handles.modelData{handles.currentModel}.maxEpochNr),'Warning'));
                    else
                        dcm_method = cellfun(@(field) strcmpi(field(1).modeltype, 'dcm'),...
                            handles.modelData{handles.currentModel}.model.model);
                        pspm_rev_dcm(handles.modelData{handles.currentModel}.model.model{dcm_method}, 'sf', epochNr, []);
                    end
            end
            set(handles.textStatus,'String',tmpStatusString);
            guidata(hObject, handles);
        end

        % Button pushed function: buttonPlot2
        function buttonPlot2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to buttonPlot2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            tmpStatusString = get(handles.textStatus,'String');
            set(handles.textStatus,'String','Plotting is in progress. Please wait...');
            switch handles.modelData{handles.currentModel}.modeltype
                case 'glm'
                    handles.modelData{handles.currentModel}.fig = pspm_rev_glm(handles.modelData{handles.currentModel}.modelfile, handles.modelData{handles.currentModel}.model, 2);
            
                case 'dcm'
                    sessionNr = checkSessionNr(app, handles);
                    if sessionNr
                        maxTrialNr = numel(handles.modelData{handles.currentModel}.model.sn{sessionNr}.u);
                        trialNr = str2num(get(handles.editTrialNr,'String'));
                        if isempty(trialNr) || trialNr > maxTrialNr || trialNr < 1
                            uiwait(msgbox(sprintf('Trial number has to be within the range [1 - %d]',maxTrialNr),'Warning'));
                        else
                            pspm_rev_dcm(handles.modelData{handles.currentModel}.model, 'inv', sessionNr, trialNr);
                        end
                    end
            
            end
            set(handles.textStatus,'String',tmpStatusString);
            guidata(hObject, handles);
        end

        % Button pushed function: buttonPlot3
        function buttonPlot3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to buttonPlot3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            tmpStatusString = get(handles.textStatus,'String');
            set(handles.textStatus,'String','Plotting is in progress. Please wait...');
            switch handles.modelData{handles.currentModel}.modeltype
                case 'glm'
                    handles.modelData{handles.currentModel}.fig = pspm_rev_glm(handles.modelData{handles.currentModel}.modelfile, handles.modelData{handles.currentModel}.model, 3);
            
                case 'dcm'
                    sessionNr = checkSessionNr(app, handles);
                    if sessionNr
                        pspm_rev_dcm(handles.modelData{handles.currentModel}.model, 'scrf', sessionNr, []);
                    end
            
            end
            set(handles.textStatus,'String',tmpStatusString);
            guidata(hObject, handles);
        end

        % Button pushed function: buttonPlot4
        function buttonPlot4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to buttonPlot4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            tmpStatusString = get(handles.textStatus,'String');
            set(handles.textStatus,'String','Plotting is in progress. Please wait...');
            switch handles.modelData{handles.currentModel}.modeltype
                case 'glm'
                    handles.modelData{handles.currentModel}.fig = pspm_rev_glm(handles.modelData{handles.currentModel}.modelfile, handles.modelData{handles.currentModel}.model, 4);
                case 'dcm'
                    pspm_rev_dcm(handles.modelData{handles.currentModel}.model, 'names');
            end
            set(handles.textStatus,'String',tmpStatusString);
            guidata(hObject, handles);
        end

        % Button pushed function: buttonPlot5
        function buttonPlot5_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to buttonPlot5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            tmpStatusString = get(handles.textStatus,'String');
            set(handles.textStatus,'String','Plotting is in progress. Please wait...');
            switch handles.modelData{handles.currentModel}.modeltype
                case 'glm'
                    handles.modelData{handles.currentModel}.fig = pspm_rev_glm(handles.modelData{handles.currentModel}.modelfile, handles.modelData{handles.currentModel}.model, 5);
                case 'dcm'
                    handles.modelData{handles.currentModel}.fig = pspm_rev_con(handles.modelData{handles.currentModel}.model);
            
            end
            set(handles.textStatus,'String',tmpStatusString);
            guidata(hObject, handles);
        end

        % Button pushed function: buttonPlot6
        function buttonPlot6_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to buttonPlot6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            tmpStatusString = get(handles.textStatus,'String');
            set(handles.textStatus,'String','Plotting is in progress. Please wait...');
            switch handles.modelData{handles.currentModel}.modeltype
                case 'glm'
                    handles.modelData{handles.currentModel}.fig = pspm_rev_con(handles.modelData{handles.currentModel}.model);
            
            end
            set(handles.textStatus,'String',tmpStatusString);
            guidata(hObject, handles);
        end

        % Button pushed function: buttonPlotClose
        function buttonPlotClose_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to buttonPlotClose (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            closeFigures(app, handles)
        end

        % Button pushed function: buttonRemoveModel
        function buttonRemoveModel_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to buttonRemoveModel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.modelData{handles.currentModel} = [];
            handles.modelData(cellfun('isempty',handles.modelData)) = [];
            handles.listModelEntry{handles.currentModel} = [];
            handles.listModelEntry(cellfun('isempty',handles.listModelEntry)) = [];
            handles.modelCnt = handles.modelCnt - 1;
            set(handles.listModel, 'String', handles.listModelEntry);
            if handles.currentModel > handles.modelCnt
                handles.currentModel = handles.modelCnt;
            end
            set(handles.listModel, 'Value', handles.currentModel);
            if handles.modelCnt < 2
                set(handles.buttonRemoveModel, 'Enable', 'off');
            end
            showModel(app, handles);
            
            guidata(hObject, handles);
        end

        % Value changed function: listModel
        function listModel_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listModel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listModel contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listModel
            entryNr = get(handles.listModel,'Value');
            if ~isempty(entryNr)
                handles.currentModel = entryNr;
                showModel(app, handles);
                guidata(hObject, handles)
            end
        end

        % Close request function: pspm_review
        function pspm_review_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pspm_review (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: delete(hObject) closes the figure
            closeFigures(app, handles);
            delete(gcbf)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.pspm_review = uifigure('Visible', 'off');
            app.pspm_review.Name = 'First Level Review Manager';
            app.pspm_review.CloseRequestFcn = createCallbackFcn(app, @pspm_review_CloseRequestFcn, true);
            app.pspm_review.Tag = 'pspm_review';
            app.panelPlot = uibuttongroup(app.pspm_review);
            app.panelPlot.Title = 'Figure Selection';
            app.panelPlot.Tag = 'panelPlot';
            app.buttonPlot1 = uibutton(app.panelPlot, 'push');
            app.buttonPlot1.ButtonPushedFcn = createCallbackFcn(app, @buttonPlot1_Callback, true);
            app.buttonPlot1.Tag = 'buttonPlot1';
            app.buttonPlot1.Visible = 'off';
            app.buttonPlot1.Text = 'Plot';
            app.buttonPlot3 = uibutton(app.panelPlot, 'push');
            app.buttonPlot3.ButtonPushedFcn = createCallbackFcn(app, @buttonPlot3_Callback, true);
            app.buttonPlot3.Tag = 'buttonPlot3';
            app.buttonPlot3.Visible = 'off';
            app.buttonPlot3.Text = 'Plot';
            app.buttonPlot4 = uibutton(app.panelPlot, 'push');
            app.buttonPlot4.ButtonPushedFcn = createCallbackFcn(app, @buttonPlot4_Callback, true);
            app.buttonPlot4.Tag = 'buttonPlot4';
            app.buttonPlot4.Visible = 'off';
            app.buttonPlot4.Text = 'Plot';
            app.buttonPlot5 = uibutton(app.panelPlot, 'push');
            app.buttonPlot5.ButtonPushedFcn = createCallbackFcn(app, @buttonPlot5_Callback, true);
            app.buttonPlot5.Tag = 'buttonPlot5';
            app.buttonPlot5.Visible = 'off';
            app.buttonPlot5.Text = 'Plot';
            app.textPlot1 = uilabel(app.panelPlot);
            app.textPlot1.Tag = 'textPlot1';
            app.textPlot1.Visible = 'off';
            app.textPlot1.Text = 'Static Text';
            app.textPlot2 = uilabel(app.panelPlot);
            app.textPlot2.Tag = 'textPlot2';
            app.textPlot2.Visible = 'off';
            app.textPlot2.Text = 'Static Text';
            app.textPlot3 = uilabel(app.panelPlot);
            app.textPlot3.Tag = 'textPlot3';
            app.textPlot3.Visible = 'off';
            app.textPlot3.Text = 'Static Text';
            app.textPlot4 = uilabel(app.panelPlot);
            app.textPlot4.Tag = 'textPlot4';
            app.textPlot4.Visible = 'off';
            app.textPlot4.Text = 'Static Text';
            app.textPlot5 = uilabel(app.panelPlot);
            app.textPlot5.Tag = 'textPlot5';
            app.textPlot5.Visible = 'off';
            app.textPlot5.Text = 'Static Text';
            app.buttonPlotClose = uibutton(app.panelPlot, 'push');
            app.buttonPlotClose.ButtonPushedFcn = createCallbackFcn(app, @buttonPlotClose_Callback, true);
            app.buttonPlotClose.Tag = 'buttonPlotClose';
            app.buttonPlotClose.Visible = 'off';
            app.buttonPlotClose.Text = 'Close all plots';
            app.editTrialNr = uieditfield(app.panelPlot, 'text');
            app.editTrialNr.Tag = 'editTrialNr';
            app.editTrialNr.Visible = 'off';
            app.editTrialNr.Value = '1';
            app.textSessionNr = uilabel(app.panelPlot);
            app.textSessionNr.Tag = 'textSessionNr';
            app.textSessionNr.Visible = 'off';
            app.textSessionNr.Text = 'Session number:';
            app.textSessionRange = uilabel(app.panelPlot);
            app.textSessionRange.Tag = 'textSessionRange';
            app.textSessionRange.Visible = 'off';
            app.textSessionRange.Text = '[1 - 100]';
            app.editSessionNr = uieditfield(app.panelPlot, 'text');
            app.editSessionNr.Tag = 'editSessionNr';
            app.editSessionNr.Visible = 'off';
            app.editSessionNr.Value = '1';
            app.buttonPlot2 = uibutton(app.panelPlot, 'push');
            app.buttonPlot2.ButtonPushedFcn = createCallbackFcn(app, @buttonPlot2_Callback, true);
            app.buttonPlot2.Tag = 'buttonPlot2';
            app.buttonPlot2.Visible = 'off';
            app.buttonPlot2.Text = 'Plot';
            app.editEpochNr = uieditfield(app.panelPlot, 'text');
            app.editEpochNr.Tag = 'editEpochNr';
            app.editEpochNr.Visible = 'off';
            app.editEpochNr.Value = '1';
            app.buttonPlot6 = uibutton(app.panelPlot, 'push');
            app.buttonPlot6.ButtonPushedFcn = createCallbackFcn(app, @buttonPlot6_Callback, true);
            app.buttonPlot6.Tag = 'buttonPlot6';
            app.buttonPlot6.Visible = 'off';
            app.buttonPlot6.Text = 'Plot';
            app.textPlot6 = uilabel(app.panelPlot);
            app.textPlot6.Tag = 'textPlot6';
            app.textPlot6.Visible = 'off';
            app.textPlot6.Text = 'Static Text';
            app.panelStatus = uipanel(app.pspm_review);
            app.panelStatus.Title = 'Status';
            app.panelStatus.Tag = 'panelStatus';
            app.textStatus = uilabel(app.panelStatus);
            app.textStatus.Tag = 'textStatus';
            app.textStatus.Text = '';
            app.buttonExit = uibutton(app.pspm_review, 'push');
            app.buttonExit.ButtonPushedFcn = createCallbackFcn(app, @buttonExit_Callback, true);
            app.buttonExit.Tag = 'buttonExit';
            app.buttonExit.Text = 'Exit';
            app.panelModel = uipanel(app.pspm_review);
            app.panelModel.Title = 'Models';
            app.panelModel.Tag = 'panelModel';
            app.listModel = uilistbox(app.panelModel);
            app.listModel.Items = {};
            app.listModel.ValueChangedFcn = createCallbackFcn(app, @listModel_Callback, true);
            app.listModel.Tag = 'listModel';
            app.listModel.Value = {};
            app.buttonAddModel = uibutton(app.panelModel, 'push');
            app.buttonAddModel.ButtonPushedFcn = createCallbackFcn(app, @buttonAddModel_Callback, true);
            app.buttonAddModel.Tag = 'buttonAddModel';
            app.buttonAddModel.Text = 'Add Model';
            app.buttonRemoveModel = uibutton(app.panelModel, 'push');
            app.buttonRemoveModel.ButtonPushedFcn = createCallbackFcn(app, @buttonRemoveModel_Callback, true);
            app.buttonRemoveModel.Tag = 'buttonRemoveModel';
            app.buttonRemoveModel.Enable = 'off';
            app.buttonRemoveModel.Text = 'Remove Model';
            % Show the figure after all components are created
            app.pspm_review.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pspm_review_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.pspm_review)

                % Execute the startup function
                runStartupFcn(app, @(app)pspm_review_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.pspm_review)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.pspm_review)
        end
    end
end