classdef pspm_appdesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1              matlab.ui.Figure
        PsPMButton_2         matlab.ui.control.Button
        Version510Build19042021withMATLAB2020bUpdate5onmacOS1123Label  matlab.ui.control.Label
        showforum            matlab.ui.control.Button
        showhelp             matlab.ui.control.Button
        rev2                 matlab.ui.control.Button
        txtMore              matlab.ui.control.Label
        ppDataPreparation    matlab.ui.control.DropDown
        ppDataPreprocessing  matlab.ui.control.DropDown
        txtDataPreproc       matlab.ui.control.Label
        GLM                  matlab.ui.control.DropDown
        batch_pushbutton     matlab.ui.control.Button
        Other_utils          matlab.ui.control.DropDown
        Other_utils_text     matlab.ui.control.Label
        SF                   matlab.ui.control.Button
        DCM                  matlab.ui.control.Button
        Level_2_text         matlab.ui.control.Label
        Level1_text          matlab.ui.control.Label
        Import_PP_header     matlab.ui.control.Label
        Export_data          matlab.ui.control.Button
        con1                 matlab.ui.control.Button
        rev1                 matlab.ui.control.Button
        con2                 matlab.ui.control.Button
        QuitGUI              matlab.ui.control.Button
    end

    
    methods (Access = private)
        function Import_data_Callback(app, hObject, eventdata, handles)
            % hObject    handle to Import_data (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % pspm_import_UI;
            cfg_add_module('pspm.prep.import');
        end
        
        function Trim_data_Callback(app, hObject, eventdata, handles)
            % hObject    handle to Trim_data (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % pspm_trim_UI;
            cfg_add_module('pspm.prep.trim');
        end
        
        function dispdata_Callback(app, hObject, eventdata, handles)
            % hObject    handle to dispdata (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % pspm_disp;
            cfg_add_module('pspm.tools.disp');
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Load data for component configuration
            componentData = load('pspm_appdesigner.mat');
            
            % Set component properties that require runtime configuration
            app.Other_utils.UserData = componentData.Other_utils.UserData;
            app.ppDataPreprocessing.UserData = componentData.ppDataPreprocessing.UserData;
            app.ppDataPreparation.UserData = componentData.ppDataPreparation.UserData;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function PsPM_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to PsPM (see VARARGIN)
            pspm_init;
            cfg_util('initcfg'); % This must be the first call to cfg_util
            %cfg_ui('Visible','off'); % Create invisible batch ui
            
            % Choose default command line output for PsPM
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: DCM
        function DCM_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to DCM (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % pspm_dcm_UI;
            cfg_add_module('pspm.first_level.scr.dcm');
        end

        % Button pushed function: Export_data
        function Export_data_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Export_data (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % pspm_exp_UI;
            cfg_add_module('pspm.first_level.export');
        end

        % Value changed function: GLM
        function GLM_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to GLM (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns GLM contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from GLM
            
            selected = get(hObject,'Value');
            
            switch selected
                case 1
                    cfg_add_module('pspm.first_level.scr.glm_scr');
                case 2
                    cfg_add_module('pspm.first_level.hp.glm_hp_e');
                case 3
                    cfg_add_module('pspm.first_level.hp.glm_hp_fc');
                case 4
                    cfg_add_module('pspm.first_level.ps.glm_ps_fc');
                case 5
                    cfg_add_module('pspm.first_level.resp.glm_ra_e');
                case 6
                    cfg_add_module('pspm.first_level.resp.glm_ra_fc');
                case 7
                    cfg_add_module('pspm.first_level.resp.glm_rp_e');
                case 8
                    cfg_add_module('pspm.first_level.resp.glm_rfr_e');
                case 9
                    cfg_add_module('pspm.first_level.sebr.glm_sebr');
                case 10
                    cfg_add_module('pspm.first_level.sps.glm_sps');
            end
        end

        % Value changed function: Other_utils
        function Other_utils_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Other_utils (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns Other_utils contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from Other_utils
            val = get(hObject,'Value');
            switch val
                case 1
                    pspm_display;
                case 2
                    cfg_add_module('pspm.tools.rename');
                case 3
                    cfg_add_module('pspm.tools.split_sessions');
                case 4
                    cfg_add_module('pspm.tools.merge');
                case 5
                    cfg_add_module('pspm.tools.artefact_rm');
                case 6
                    cfg_add_module('pspm.tools.downsample');
                case 7
                    cfg_add_module('pspm.tools.interpolate');
                case 8
                    cfg_add_module('pspm.tools.extract_segments');
                case 9
                    cfg_add_module('pspm.tools.segment_mean');
                case 10
                    cfg_add_module('pspm.tools.extract_markerinfo');
                case 11
                    pspm_data_editor();
                case 12
                    cfg_add_module('pspm.tools.convert_data');
            end
        end

        % Button pushed function: showforum
        function PsPM_ButtonDownFcnForum(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to PsPM (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            pspm_show_forum;
        end

        % Button pushed function: showhelp
        function PsPM_ButtonDownFcnHelp(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to PsPM (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            pspm_show_help_doc;
        end

        % Button pushed function: QuitGUI
        function QuitGUI_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to QuitGUI (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            pspm_quit; return
        end

        % Button pushed function: SF
        function SF_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to SF (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % pspm_sf_UI;
            cfg_add_module('pspm.first_level.scr.sf');
        end

        % Button pushed function: batch_pushbutton
        function batch_pushbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to batch_pushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cfg_ui;
        end

        % Button pushed function: con1
        function con1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to con1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % pspm_con1_UI;
            pspm_contrast;
        end

        % Button pushed function: con2
        function con2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to con2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % pspm_con2_UI;
            cfg_add_module('pspm.second_level.contrast');
        end

        % Value changed function: ppDataPreparation
        function ppDataPreparation_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ppDataPreparation (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns ppDataPreparation contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from ppDataPreparation
            
            selected = get(hObject,'Value');
            
            switch selected
                case 1
                    cfg_add_module('pspm.prep.import');
                case 2
                    cfg_add_module('pspm.prep.trim');
            end
        end

        % Value changed function: ppDataPreprocessing
        function ppDataPreprocessing_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ppDataPreprocessing (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns ppDataPreprocessing contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from ppDataPreprocessing
            
            selected = get(hObject,'Value');
            
            switch selected
                case 1
                    cfg_add_module('pspm.data_preprocessing.pp_heart_period.pp_heart_data');
                case 2
                    %cfg_add_module('pspm.data_preprocessing.pp_heart_period.ecg_editor');
                    pspm_ecg_editor();
                case 3
                    cfg_add_module('pspm.data_preprocessing.resp_pp');
                case 4
                    cfg_add_module('pspm.data_preprocessing.pp_pupil.process_illuminance');
                case 5
                    cfg_add_module('pspm.data_preprocessing.pp_pupil.find_valid_fixations');
                case 6
                    cfg_add_module('pspm.data_preprocessing.pp_pupil.pupil_correct');
                case 7
                    cfg_add_module('pspm.data_preprocessing.pp_pupil.pupil_preprocess');
                case 8
                    cfg_add_module('pspm.data_preprocessing.pupil_size_convert');
                case 9
                    cfg_add_module('pspm.data_preprocessing.gaze_convert');
                %case 10
                %    cfg_add_module('pspm.tools.convert_data');
                case 10
                    cfg_add_module('pspm.data_preprocessing.pp_emg.find_sounds');
                case 11
                    cfg_add_module('pspm.data_preprocessing.pp_emg.pp_emg_data');
                case 12
                    cfg_add_module('pspm.data_preprocessing.pp_scr');
            end
        end

        % Button pushed function: rev1
        function rev1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to rev1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %pspm_rev1_UI;
            pspm_review;
        end

        % Button pushed function: rev2
        function rev2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to rev2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cfg_add_module('pspm.second_level.report');
        end

        % Callback function
        function PsPMButtonPushed(app, event)
            pspm_show_arms;
        end

        % Button pushed function: PsPMButton_2
        function PsPMButton_2Pushed(app, event)
            pspm_show_arms;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'pspm';
            app.figure1.Tag = 'figure1';
            app.QuitGUI = uibutton(app.figure1, 'push');
            app.QuitGUI.ButtonPushedFcn = createCallbackFcn(app, @QuitGUI_Callback, true);
            app.QuitGUI.Tag = 'QuitGUI';
            app.QuitGUI.Tooltip = 'Goodbye.';
            app.QuitGUI.Text = 'Quit';
            app.con2 = uibutton(app.figure1, 'push');
            app.con2.ButtonPushedFcn = createCallbackFcn(app, @con2_Callback, true);
            app.con2.Tag = 'con2';
            app.con2.Tooltip = 'Define group level model';
            app.con2.Text = 'Second Level Model';
            app.rev1 = uibutton(app.figure1, 'push');
            app.rev1.ButtonPushedFcn = createCallbackFcn(app, @rev1_Callback, true);
            app.rev1.Tag = 'rev1';
            app.rev1.Tooltip = 'Review a first level model';
            app.rev1.Text = 'Review Model';
            app.con1 = uibutton(app.figure1, 'push');
            app.con1.ButtonPushedFcn = createCallbackFcn(app, @con1_Callback, true);
            app.con1.Tag = 'con1';
            app.con1.Tooltip = 'First level contrasts for various analysis methods';
            app.con1.Text = 'Contrast Manager';
            app.Export_data = uibutton(app.figure1, 'push');
            app.Export_data.ButtonPushedFcn = createCallbackFcn(app, @Export_data_Callback, true);
            app.Export_data.Tag = 'Export_data';
            app.Export_data.Tooltip = 'Export your data for group level analysis outside PsPM/SCRalyze';
            app.Export_data.Text = 'Export Statistics';
            app.Import_PP_header = uilabel(app.figure1);
            app.Import_PP_header.Tag = 'Import_PP_header';
            app.Import_PP_header.Text = 'Data Preparation';
            app.Level1_text = uilabel(app.figure1);
            app.Level1_text.Tag = 'Level1_text';
            app.Level1_text.Text = 'First Level Models';
            app.Level_2_text = uilabel(app.figure1);
            app.Level_2_text.Tag = 'Level_2_text';
            app.Level_2_text.Text = 'Second Level';
            app.DCM = uibutton(app.figure1, 'push');
            app.DCM.ButtonPushedFcn = createCallbackFcn(app, @DCM_Callback, true);
            app.DCM.Tag = 'DCM';
            app.DCM.Tooltip = 'Dynamic causal modelling';
            app.DCM.Text = 'Non-linear SCR model';
            app.SF = uibutton(app.figure1, 'push');
            app.SF.ButtonPushedFcn = createCallbackFcn(app, @SF_Callback, true);
            app.SF.Tag = 'SF';
            app.SF.Tooltip = 'Spontaneous fluctuations and skin conductance level';
            app.SF.Text = 'Models for SF';
            app.Other_utils_text = uilabel(app.figure1);
            app.Other_utils_text.Tag = 'Other_utils_text';
            app.Other_utils_text.Text = 'Tools';
            app.Other_utils = uidropdown(app.figure1);
            app.Other_utils.Items = {'Display data', 'Rename file', 'Split sessions', 'Merge files', 'Artefact removal', 'Downsample data', 'Interpolate missing data', 'Extract segments', 'Segment mean', 'Extract event marker info', 'Data editor', 'Data convert'};
            app.Other_utils.ValueChangedFcn = createCallbackFcn(app, @Other_utils_Callback, true);
            app.Other_utils.Tag = 'Other_utils';
            app.Other_utils.Tooltip = 'Various utilities';
            app.Other_utils.Value = 'Display data';
            app.batch_pushbutton = uibutton(app.figure1, 'push');
            app.batch_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @batch_pushbutton_Callback, true);
            app.batch_pushbutton.Tag = 'batch_pushbutton';
            app.batch_pushbutton.Tooltip = 'Batch Editor';
            app.batch_pushbutton.Text = 'Batch';
            app.GLM = uidropdown(app.figure1);
            app.GLM.Items = {'GLM for SCR                    ', 'GLM for HPR (evoked)            ', 'GLM for HPR (fear-conditioning)', 'GLM for PSR (fear-conditioning)', 'GLM for RAR (evoked)', 'GLM for RAR (fear-conditioning)', 'GLM for RPR (evoked)', 'GLM for RFRR (evoked)', 'GLM for SEBR', 'GLM for SPS'};
            app.GLM.ValueChangedFcn = createCallbackFcn(app, @GLM_Callback, true);
            app.GLM.Tag = 'GLM';
            app.GLM.Value = 'GLM for SCR                    ';
            app.txtDataPreproc = uilabel(app.figure1);
            app.txtDataPreproc.Tag = 'txtDataPreproc';
            app.txtDataPreproc.Text = 'Data Preprocessing';
            app.ppDataPreprocessing = uidropdown(app.figure1);
            app.ppDataPreprocessing.Items = {'Preprocess heart data', 'ECG editor', 'Preprocess respiration data', 'Prepare illuminance GLM', 'Find valid fixations', 'Pupil foreshortening error correction', 'Pupil preprocessing', 'Pupil size convert', 'Gaze convert', 'Find startle sound onsets', 'Preprocess startle eyeblink EMG', 'Preprocessing SCR'};
            app.ppDataPreprocessing.ValueChangedFcn = createCallbackFcn(app, @ppDataPreprocessing_Callback, true);
            app.ppDataPreprocessing.Tag = 'ppDataPreprocessing';
            app.ppDataPreprocessing.Tooltip = 'Data preprocessing';
            app.ppDataPreprocessing.Value = 'Preprocess heart data';
            app.ppDataPreparation = uidropdown(app.figure1);
            app.ppDataPreparation.Items = {'Import', 'Trim'};
            app.ppDataPreparation.ValueChangedFcn = createCallbackFcn(app, @ppDataPreparation_Callback, true);
            app.ppDataPreparation.Tag = 'ppDataPreparation';
            app.ppDataPreparation.Tooltip = 'Data preparation';
            app.ppDataPreparation.Value = 'Import';
            app.txtMore = uilabel(app.figure1);
            app.txtMore.Tag = 'txtMore';
            app.txtMore.Text = 'More';
            app.rev2 = uibutton(app.figure1, 'push');
            app.rev2.ButtonPushedFcn = createCallbackFcn(app, @rev2_Callback, true);
            app.rev2.Tag = 'rev2';
            app.rev2.Tooltip = 'Report 2nd level model';
            app.rev2.Text = 'Report Second Level';
            app.showhelp = uibutton(app.figure1, 'push');
            app.showhelp.ButtonPushedFcn = createCallbackFcn(app, @PsPM_ButtonDownFcnHelp, true);
            app.showhelp.Tag = 'pushbutton24';
            app.showhelp.Tooltip = 'Help!';
            app.showhelp.Text = 'Help';
            app.showforum = uibutton(app.figure1, 'push');
            app.showforum.ButtonPushedFcn = createCallbackFcn(app, @PsPM_ButtonDownFcnForum, true);
            app.showforum.Tag = 'pushbutton25';
            app.showforum.Tooltip = 'Help!';
            app.showforum.Text = 'Open Issue';
            app.Version510Build19042021withMATLAB2020bUpdate5onmacOS1123Label = uilabel(app.figure1);
            app.Version510Build19042021withMATLAB2020bUpdate5onmacOS1123Label.Text = {'Version 6.1'; 'Build 13092022 with MATLAB 2022a'; 'The PsPM Team, University College London'};
            app.PsPMButton_2 = uibutton(app.figure1, 'push');
            app.PsPMButton_2.ButtonPushedFcn = createCallbackFcn(app, @PsPMButton_2Pushed, true);
            app.PsPMButton_2.Text = 'PsPM';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pspm_appdesigner(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)PsPM_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end