classdef pspm_contrast_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        panelStatstype        matlab.ui.container.ButtonGroup
        checkboxZscored       matlab.ui.control.CheckBox
        checkboxDeleteCon     matlab.ui.control.CheckBox
        radioRecon            matlab.ui.control.RadioButton
        radioCond             matlab.ui.control.RadioButton
        radioParam            matlab.ui.control.RadioButton
        panelModel            matlab.ui.container.Panel
        buttonRun             matlab.ui.control.Button
        buttonLoadModel       matlab.ui.control.Button
        panelContrastNames    matlab.ui.container.Panel
        buttonDeleteContrast  matlab.ui.control.Button
        buttonClearCon        matlab.ui.control.Button
        panelDefineConName    matlab.ui.container.Panel
        buttonNewContrast     matlab.ui.control.Button
        editContrastName      matlab.ui.control.EditField
        listContrastNames     matlab.ui.control.ListBox
        panelGroup            matlab.ui.container.ButtonGroup
        radioGroup3           matlab.ui.control.RadioButton
        radioGroup2           matlab.ui.control.RadioButton
        radioGroup1           matlab.ui.control.RadioButton
        panelTestDef          matlab.ui.container.ButtonGroup
        radioQuadEffects      matlab.ui.control.RadioButton
        radioCondDiff         matlab.ui.control.RadioButton
        radioIntercept        matlab.ui.control.RadioButton
        panelStatus           matlab.ui.container.Panel
        textStatus            matlab.ui.control.Label
        buttonExit            matlab.ui.control.Button
        panelNames            matlab.ui.container.Panel
        listNames             matlab.ui.control.ListBox
    end

    
    methods (Access = private)
        function adjustStatsTypeDisplay(app, handles)
            
            switch handles.modeltype
                case {'sf','dcm'}
                    set(handles.radioParam, 'String', 'show all trials');
                    set(handles.radioCond, 'String', 'show conditions');
                    set(handles.radioRecon, 'Visible', 'off');
                case 'glm'
                    set(handles.radioRecon, 'Visible', 'on');
                    set(handles.radioParam, 'String', 'show all basis functions');
                    set(handles.radioCond, 'String', 'show only first basis function');
                    set(handles.radioRecon, 'String', 'show combined basis function');
            end
        end
        
        function handles = clearNamesColor(app, handles)
            handles.conArray{handles.currentContrast}.namesString = handles.names;
            handles.conArray{handles.currentContrast}.contrasts = zeros(size(handles.conArray{handles.currentContrast}.contrasts));
            set(handles.listNames,'String',handles.conArray{handles.currentContrast}.namesString);
        end
        
        function disableButtonGroups(app, handles)
            set(handles.radioGroup1,'Enable','off');
            set(handles.radioGroup2,'Enable','off');
            set(handles.radioGroup3,'Enable','off');
            set(handles.radioGroup1,'String','Group 1');
            set(handles.radioGroup2,'String','Group 2');
            set(handles.radioGroup3,'String','Group 3');
            
            set(handles.radioIntercept,'Enable','off');
            set(handles.radioCondDiff,'Enable','off');
            set(handles.radioQuadEffects,'Enable','off');
            
            set(handles.radioParam,'Enable','off');
            set(handles.radioCond,'Enable','off');
            set(handles.radioRecon,'Enable','off');
            set(handles.checkboxZscored, 'Enable', 'off');
        end
        
        function enableButtonGroups(app, handles)
            set(handles.radioGroup1,'Enable','on');
            set(handles.radioGroup1,'Value',1);
            set(handles.radioGroup1,'String',sprintf('<HTML><FONT bgcolor="%s">%s</Font></html>',handles.colorString{1},'Group 1'));
            
            set(handles.radioIntercept,'Enable','on');
            set(handles.radioIntercept,'Value',1);
            set(handles.radioCondDiff,'Enable','on');
            set(handles.radioQuadEffects,'Enable','on');
            
            switch handles.modeltype
                case {'sf','dcm'}
                    set(handles.radioParam, 'Enable', 'on');
                    set(handles.radioCond, 'Enable', 'on');
                    set(handles.checkboxZscored, 'Enable', 'on');
                    if ~strcmpi(handles.modeltype, 'sf')
                        set(handles.radioCond, 'Enable', 'on');
                    end
                case {'glm'}
                    set(handles.radioParam, 'Enable', 'on');
                    set(handles.radioRecon, 'Enable', 'on');
                    set(handles.radioCond, 'Enable', 'on');
            end
        end
        
        function val = getGroupValue(app, handles)
            val(1) = get(handles.radioGroup1,'Value');
            val(2) = get(handles.radioGroup2,'Value');
            val(3) = get(handles.radioGroup3,'Value');
            val = find(val);
        end
        
        function val = getStatsTypeValue(app, handles)
            val(1) = get(handles.radioParam,'Value');
            val(2) = get(handles.radioCond,'Value');
            val(3) = get(handles.radioRecon,'Value');
            val = find(val);
        end
        
        function val = getTestGroupValue(app, handles)
            val(1) = get(handles.radioIntercept,'Value');
            val(2) = get(handles.radioCondDiff,'Value');
            val(3) = get(handles.radioQuadEffects,'Value');
            val = find(val);
        end
        
        function popupDatatype_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupDatatype (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function setContrastGroup(app, handles, nr)
            set(handles.radioGroup1,'Enable','on');
            set(handles.radioGroup1,'Value',1);
            if nr > 1
                set(handles.radioGroup2,'Enable','on');
                set(handles.radioGroup2,'String',sprintf('<HTML><FONT bgcolor="%s">%s</Font></html>',handles.colorString{2},'Group 2'));
            else
                set(handles.radioGroup2,'Enable','off');
                set(handles.radioGroup2,'String','Group 2');
            end
            if nr > 2
                set(handles.radioGroup3,'Enable','on');
                set(handles.radioGroup3,'String',sprintf('<HTML><FONT bgcolor="%s">%s</Font></html>',handles.colorString{3},'Group 3'));
            else
                set(handles.radioGroup3,'Enable','off');
                set(handles.radioGroup3,'String','Group 3');
            end
        end
        
        function setTestGroupValue(app, handles, val)
            switch val
                case 1
                    set(handles.radioIntercept,'Value',1);
                case 2
                    set(handles.radioCondDiff,'Value',1);
                case 3
                    set(handles.radioQuadEffects,'Value',1);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function pspm_contrast_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to pspm_contrast (see VARARGIN)
            
            % Left alignment of text fields
            set(handles.textStatus,'HorizontalAlignment','left')
            set(handles.editContrastName,'HorizontalAlignment','left')
            
            set(handles.textStatus,'String','Select a model...');
            
            handles.listNamesValues = 1;
            
            handles.colorString = {'red', 'green', 'fuchsia'};
            
            handles.contrastCnt = 0;
            handles.currentContrast = 1;
            
            handles.contrastNamesString = [];
            
            handles.enableGroupButtons = false;
            
            % Choose default command line output for pspm_contrast
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: buttonClearCon
        function buttonClearCon_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to buttonClearCon (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles = clearNamesColor(app, handles);
            guidata(hObject, handles);
        end

        % Button pushed function: buttonDeleteContrast
        function buttonDeleteContrast_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to buttonDeleteContrast (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.conArray{handles.currentContrast} = [];
            handles.conArray(cellfun('isempty',handles.conArray)) = [];
            handles.contrastNamesString{handles.currentContrast} = [];
            handles.contrastNamesString(cellfun('isempty',handles.contrastNamesString)) = [];
            handles.contrastCnt = handles.contrastCnt - 1;
            if handles.currentContrast > handles.contrastCnt
                handles.currentContrast = handles.contrastCnt;
            end
            set(handles.listContrastNames, 'Value', handles.currentContrast);
            set(handles.listContrastNames, 'String', handles.contrastNamesString);
            set(handles.listNames, 'String', handles.conArray{handles.currentContrast}.namesString);
            setTestGroupValue(app, handles, handles.conArray{handles.currentContrast}.testGroupVal);
            setContrastGroup(app, handles, handles.conArray{handles.currentContrast}.testGroupVal)
            
            if handles.contrastCnt < 2
                set(handles.buttonDeleteContrast, 'Enable', 'off');
            end
            
            guidata(hObject, handles);
        end

        % Button pushed function: buttonExit
        function buttonExit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to buttonExit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            delete(gcbf)
        end

        % Button pushed function: buttonLoadModel
        function buttonLoadModel_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to buttonLoadModel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            filt='.*\.mat$';
            [modelfile, sts]=spm_select(1, filt, 'Select model file');
            if sts==0, return; end;
            
            handles.modelfile = modelfile;
            
            % Init
            handles.conArray = [];
            handles.contrastCnt = 0;
            handles.currentContrast = 1;
            handles.contrastNamesString = [];
            handles.names = [];
            set(handles.listNames, 'String', handles.names);
            set(handles.listNames, 'Value', 1);
            set(handles.listNames,'Enable','off');
            set(handles.listContrastNames,'String',handles.contrastNamesString);
            set(handles.listContrastNames, 'Value', 1);
            set(handles.listContrastNames,'Enable','off');
            set(handles.buttonNewContrast,'Enable','off');
            set(handles.buttonDeleteContrast,'Enable','off');
            set(handles.buttonRun,'Enable','off');
            set(handles.buttonClearCon,'Enable','off');
            set(handles.checkboxDeleteCon,'Enable','off');
            disableButtonGroups(app, handles)
            handles.enableGroupButtons = false;
            
            guidata(hObject, handles);
            
            % check model file
            set(handles.textStatus,'String','Loading model. Please wait...');
            drawnow
            [sts, model, modeltype] = pspm_load1(modelfile, 'all', 'any');
            if sts == -1 || ~any(strcmp(modeltype,{'glm','dcm','sf'}))
                set(handles.textStatus,'String','No modeltype detected');
                drawnow
                return;
            elseif strcmp(modeltype,'sf')
                dcm = cellfun(@(field) strcmpi(field(1).modeltype, 'dcm'), model.model);
                if numel(dcm) < 1
                    set(handles.textStatus,'String','No supported modeltype detected');
                    drawnow
                    return;
                end
            end
            set(handles.buttonNewContrast,'Enable','on');
            set(handles.checkboxDeleteCon,'Enable','on');
            set(handles.checkboxDeleteCon,'Value',0);
            % detect model
            switch modeltype
                case 'glm'
                    set(handles.textStatus,'String','Detected modeltype: GLM');
                    drawnow
                    handles.paramnames = model.names;
                    handles.condnames = {};
                    % get condition names, code borrowed from pspm_glm_recon
                    regno = (numel(model.names) - model.interceptno)/model.bf.bfno;
                    for k = 1:regno
                        foobar = model.names{((k - 1) * model.bf.bfno + 1)};
                        foo = strfind(foobar, ', bf');
                        handles.condnames{k} = foobar(1:(foo-1));
                        clear foo foobar
                    end;
            
                case 'dcm'
                    set(handles.textStatus,'String','Detected modeltype: DCM')
                    drawnow
                    handles.paramnames = model.trlnames;
                    handles.condnames = model.condnames;
                case 'sf'
                    set(handles.textStatus,'String','Detected modeltype: SF')
                    drawnow
                    handles.paramnames = model.trlnames;
            end
            handles.names = handles.paramnames;
            handles.modeltype = modeltype;
            set(handles.listNames, 'String', handles.names);
            setTestGroupValue(app, handles, 1)
            adjustStatsTypeDisplay(app, handles);
            guidata(hObject, handles);
        end

        % Button pushed function: buttonNewContrast
        function buttonNewContrast_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to buttonNewContrast (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            handles.contrastCnt = handles.contrastCnt + 1;
            handles.currentContrast = handles.contrastCnt;
            
            handles.conArray{handles.contrastCnt}.namesString = handles.names;
            handles.conArray{handles.contrastCnt}.contrasts = zeros(3,numel(handles.names));
            handles.conArray{handles.contrastCnt}.testGroupVal = getTestGroupValue(app, handles);
            handles.conArray{handles.contrastCnt}.statstype = getStatsTypeValue(app, handles);
            
            contrastName = get(handles.editContrastName,'String');
            if isempty(contrastName)
                handles.contrastNamesString{handles.currentContrast} = ' ';
            else
                handles.contrastNamesString{handles.currentContrast} = contrastName;
            end
            set(handles.listContrastNames,'String',handles.contrastNamesString);
            set(handles.listContrastNames,'Value',handles.currentContrast);
            set(handles.listNames,'String',handles.conArray{handles.currentContrast}.namesString);
            
            if handles.enableGroupButtons == false
                set(handles.listNames,'Enable','on');
                enableButtonGroups(app, handles)
                set(handles.buttonRun,'Enable','on');
                set(handles.buttonClearCon,'Enable','on');
                set(handles.listContrastNames,'Enable','on');
                handles.enableGroupButtons = true;
            end
            
            if handles.contrastCnt > 1
                set(handles.buttonDeleteContrast, 'Enable', 'on');
            end
            
            guidata(hObject, handles);
        end

        % Button pushed function: buttonRun
        function buttonRun_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to buttonRun (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            for i=1:numel(handles.conArray)
                norm1 = sum(handles.conArray{i}.contrasts(1,:));
                if norm1 == 0
                    norm1 = 1;
                end
                norm2 = sum(handles.conArray{i}.contrasts(2,:));
                if norm2 == 0
                    norm2 = 1;
                end
                norm3 = sum(handles.conArray{i}.contrasts(3,:));
                if norm3 == 0
                    norm3 = 1;
                end
            
                switch handles.conArray{i}.testGroupVal
                    case 1
                        conVec{i} = handles.conArray{i}.contrasts(1,:)/norm1;
            
                    case 2
                        conVec{i} = handles.conArray{i}.contrasts(1,:)/norm1 - handles.conArray{i}.contrasts(2,:)/norm2;
            
                    case 3
                        conVec{i} = handles.conArray{i}.contrasts(1,:)/norm1 - 2*handles.conArray{i}.contrasts(2,:)/norm2 + handles.conArray{i}.contrasts(3,:)/norm3;
                end
            end
            
            deletecon = get(handles.checkboxDeleteCon,'Value');
            zscored = get(handles.checkboxZscored, 'Value');
            datatype = {'param', 'cond', 'recon'};
            datatype = datatype{handles.conArray{i}.statstype};
            options.zscored = zscored;
            pspm_con1(handles.modelfile, handles.contrastNamesString, conVec, datatype, deletecon, options);
        end

        % Value changed function: listContrastNames
        function listContrastNames_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listContrastNames (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listContrastNames contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listContrastNames
            entryNr = get(handles.listContrastNames,'Value');
            handles.currentContrast = entryNr;
            % reset ListboxTop
            set(handles.listNames, 'ListboxTop', 1);
            set(handles.listNames,'String',handles.conArray{handles.currentContrast}.namesString);
            setTestGroupValue(app, handles, handles.conArray{handles.currentContrast}.testGroupVal);
            setContrastGroup(app, handles, handles.conArray{handles.currentContrast}.testGroupVal)
            guidata(hObject, handles);
        end

        % Value changed function: listNames
        function listNames_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listNames (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listNames contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listNames
            listValue = get(handles.listNames, 'Value');
            lbTop = get(handles.listNames, 'ListboxTop');
            radioGroupValue = getGroupValue(app, handles);
            contrastVal = find(handles.conArray{handles.currentContrast}.contrasts(:,listValue));
            if isempty(contrastVal)
                handles.conArray{handles.currentContrast}.contrasts(radioGroupValue,listValue) = 1;
            elseif contrastVal == radioGroupValue
                handles.conArray{handles.currentContrast}.contrasts(radioGroupValue,listValue) = ~handles.conArray{handles.currentContrast}.contrasts(radioGroupValue,listValue);
            end
            
            contrastVal = find(handles.conArray{handles.currentContrast}.contrasts(:,listValue));
            if isempty(contrastVal)
                handles.conArray{handles.currentContrast}.namesString{listValue} = handles.names{listValue};
            else
                handles.conArray{handles.currentContrast}.namesString{listValue} = sprintf('<HTML><FONT bgcolor="%s">%s</Font></html>',handles.colorString{contrastVal},handles.names{listValue});
            end
            
            set(handles.listNames,'String',handles.conArray{handles.currentContrast}.namesString);
            if numel(handles.conArray{handles.currentContrast}.namesString) >= lbTop
                set(handles.listNames, 'ListboxTop', lbTop);
            end;
            guidata(hObject, handles);
        end

        % Selection changed function: panelStatstype
        function panelStatstype_SelectionChangeFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to the selected object in panelTestDef
            % eventdata  structure with the following fields (see UIBUTTONGROUP)
            %	EventName: string 'SelectionChanged' (read only)
            %	OldValue: handle of the previously selected object or empty if none was selected
            %	NewValue: handle of the currently selected object
            % handles    structure with handles and user data (see GUIDATA)
            
            % get current stats type and assign names --
            testStatstype = getStatsTypeValue(app, handles);
            if testStatstype == 1
                handles.names = handles.paramnames;
            else
                handles.names = handles.condnames;
            end
            
            if strcmpi(handles.modeltype, 'dcm') && testStatstype ~= 3
                set(handles.checkboxZscored, 'Enable', 'on');
            else
                set(handles.checkboxZscored, 'Enable', 'off');
                set(handles.checkboxZscored, 'Value', 0);
            end
            lbTop = get(handles.listNames, 'ListboxTop');
            if lbTop > numel(handles.names)
                set(handles.listNames, 'ListboxTop', 1);
            end
            lbValue = get(handles.listNames, 'Value');
            if lbValue > numel(handles.names)
                set(handles.listNames, 'Value', 1);
            end
            set(handles.listNames, 'String', handles.names);
            
            % assign stats type to contrast and initialise contrast vector --
            handles.conArray{handles.currentContrast}.namesString = handles.names;
            handles.conArray{handles.currentContrast}.statstype = testStatstype;
            handles.conArray{handles.currentContrast}.contrasts = zeros(3,numel(handles.names));
            
            guidata(hObject, handles);
        end

        % Selection changed function: panelTestDef
        function panelTestDef_SelectionChangeFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to the selected object in panelTestDef
            % eventdata  structure with the following fields (see UIBUTTONGROUP)
            %	EventName: string 'SelectionChanged' (read only)
            %	OldValue: handle of the previously selected object or empty if none was selected
            %	NewValue: handle of the currently selected object
            % handles    structure with handles and user data (see GUIDATA)
            
            testGroupVal = getTestGroupValue(app, handles);
            setContrastGroup(app, handles, testGroupVal);
            if testGroupVal ~= handles.conArray{handles.currentContrast}.testGroupVal
                handles = clearNamesColor(app, handles);
            end
            handles.conArray{handles.currentContrast}.testGroupVal = testGroupVal;
            
            guidata(hObject, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'First Level Contrast Manager';
            app.figure1.Tag = 'figure1';
            app.panelNames = uipanel(app.figure1);
            app.panelNames.Title = 'Names';
            app.panelNames.Tag = 'panelNames';
            app.listNames = uilistbox(app.panelNames);
            app.listNames.Items = {};
            app.listNames.ValueChangedFcn = createCallbackFcn(app, @listNames_Callback, true);
            app.listNames.Tag = 'listNames';
            app.listNames.Enable = 'off';
            app.listNames.Value = {};
            app.buttonExit = uibutton(app.figure1, 'push');
            app.buttonExit.ButtonPushedFcn = createCallbackFcn(app, @buttonExit_Callback, true);
            app.buttonExit.Tag = 'buttonExit';
            app.buttonExit.Text = 'Exit';
            app.panelStatus = uipanel(app.figure1);
            app.panelStatus.Title = 'Status';
            app.panelStatus.Tag = 'panelStatus';
            app.textStatus = uilabel(app.panelStatus);
            app.textStatus.Tag = 'textStatus';
            app.textStatus.Text = '';
            app.panelTestDef = uibuttongroup(app.figure1);
            app.panelTestDef.SelectionChangedFcn = createCallbackFcn(app, @panelTestDef_SelectionChangeFcn, true);
            app.panelTestDef.Title = 'Define Test';
            app.panelTestDef.Tag = 'panelTestDef';
            app.radioIntercept = uiradiobutton(app.panelTestDef);
            app.radioIntercept.Tag = 'radioIntercept';
            app.radioIntercept.Enable = 'off';
            app.radioIntercept.Text = 'Test of intercept';
            app.radioIntercept.Value = true;
            app.radioCondDiff = uiradiobutton(app.panelTestDef);
            app.radioCondDiff.Tag = 'radioCondDiff';
            app.radioCondDiff.Enable = 'off';
            app.radioCondDiff.Text = 'Test of condition difference';
            app.radioQuadEffects = uiradiobutton(app.panelTestDef);
            app.radioQuadEffects.Tag = 'radioQuadEffects';
            app.radioQuadEffects.Enable = 'off';
            app.radioQuadEffects.Text = 'Test of quadratic effects';
            app.panelGroup = uibuttongroup(app.figure1);
            app.panelGroup.Title = 'Define Group';
            app.panelGroup.Tag = 'panelGroup';
            app.radioGroup1 = uiradiobutton(app.panelGroup);
            app.radioGroup1.Tag = 'radioGroup1';
            app.radioGroup1.Enable = 'off';
            app.radioGroup1.Text = 'Group 1';
            app.radioGroup1.Value = true;
            app.radioGroup2 = uiradiobutton(app.panelGroup);
            app.radioGroup2.Tag = 'radioGroup2';
            app.radioGroup2.Enable = 'off';
            app.radioGroup2.Text = 'Group 2';
            app.radioGroup3 = uiradiobutton(app.panelGroup);
            app.radioGroup3.Tag = 'radioGroup3';
            app.radioGroup3.Enable = 'off';
            app.radioGroup3.Text = 'Group 3';
            app.panelContrastNames = uipanel(app.figure1);
            app.panelContrastNames.Title = 'Contrasts';
            app.panelContrastNames.Tag = 'panelContrastNames';
            app.listContrastNames = uilistbox(app.panelContrastNames);
            app.listContrastNames.Items = {};
            app.listContrastNames.ValueChangedFcn = createCallbackFcn(app, @listContrastNames_Callback, true);
            app.listContrastNames.Tag = 'listContrastNames';
            app.listContrastNames.Enable = 'off';
            app.listContrastNames.Value = {};
            app.panelDefineConName = uipanel(app.panelContrastNames);
            app.panelDefineConName.Title = 'Define Contrast Name';
            app.panelDefineConName.Tag = 'panelDefineConName';
            app.editContrastName = uieditfield(app.panelDefineConName, 'text');
            app.editContrastName.Tag = 'editContrastName';
            app.editContrastName.Value = 'Enter Contrast Name';
            app.buttonNewContrast = uibutton(app.panelDefineConName, 'push');
            app.buttonNewContrast.ButtonPushedFcn = createCallbackFcn(app, @buttonNewContrast_Callback, true);
            app.buttonNewContrast.Tag = 'buttonNewContrast';
            app.buttonNewContrast.Enable = 'off';
            app.buttonNewContrast.Text = 'New Contrast';
            app.buttonClearCon = uibutton(app.panelContrastNames, 'push');
            app.buttonClearCon.ButtonPushedFcn = createCallbackFcn(app, @buttonClearCon_Callback, true);
            app.buttonClearCon.Tag = 'buttonClearCon';
            app.buttonClearCon.Enable = 'off';
            app.buttonClearCon.Text = 'Clear Contrast';
            app.buttonDeleteContrast = uibutton(app.panelContrastNames, 'push');
            app.buttonDeleteContrast.ButtonPushedFcn = createCallbackFcn(app, @buttonDeleteContrast_Callback, true);
            app.buttonDeleteContrast.Tag = 'buttonDeleteContrast';
            app.buttonDeleteContrast.Enable = 'off';
            app.buttonDeleteContrast.Text = 'Delete Contrast';
            app.panelModel = uipanel(app.figure1);
            app.panelModel.Title = 'Model';
            app.panelModel.Tag = 'panelModel';
            app.buttonLoadModel = uibutton(app.panelModel, 'push');
            app.buttonLoadModel.ButtonPushedFcn = createCallbackFcn(app, @buttonLoadModel_Callback, true);
            app.buttonLoadModel.Tag = 'buttonLoadModel';
            app.buttonLoadModel.Text = 'Load Model';
            app.buttonRun = uibutton(app.panelModel, 'push');
            app.buttonRun.ButtonPushedFcn = createCallbackFcn(app, @buttonRun_Callback, true);
            app.buttonRun.Tag = 'buttonRun';
            app.buttonRun.Enable = 'off';
            app.buttonRun.Text = 'Run';
            app.panelStatstype = uibuttongroup(app.figure1);
            app.panelStatstype.SelectionChangedFcn = createCallbackFcn(app, @panelStatstype_SelectionChangeFcn, true);
            app.panelStatstype.Title = 'Define Stats Type';
            app.panelStatstype.Tag = 'panelStatstype';
            app.radioParam = uiradiobutton(app.panelStatstype);
            app.radioParam.Tag = 'radioParam';
            app.radioParam.Enable = 'off';
            app.radioParam.Text = 'show all basis functions';
            app.radioParam.Value = true;
            app.radioCond = uiradiobutton(app.panelStatstype);
            app.radioCond.Tag = 'radioCond';
            app.radioCond.Enable = 'off';
            app.radioCond.Text = 'show only first basis function';
            app.radioRecon = uiradiobutton(app.panelStatstype);
            app.radioRecon.Tag = 'radioRecon';
            app.radioRecon.Enable = 'off';
            app.radioRecon.Text = 'show combined basis functions';
            app.checkboxDeleteCon = uicheckbox(app.panelStatstype);
            app.checkboxDeleteCon.Tag = 'checkboxDeleteCon';
            app.checkboxDeleteCon.Enable = 'off';
            app.checkboxDeleteCon.Text = 'Delete existing contrasts';
            app.checkboxZscored = uicheckbox(app.panelStatstype);
            app.checkboxZscored.Tag = 'checkboxZscored';
            app.checkboxZscored.Enable = 'off';
            app.checkboxZscored.Text = 'Z-scored';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pspm_contrast_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)pspm_contrast_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end