classdef pspm_display_App_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        pspm_display        matlab.ui.Figure
        file                matlab.ui.container.Menu
        load                matlab.ui.container.Menu
        saveas              matlab.ui.container.Menu
        exit                matlab.ui.container.Menu
        uitoolbar2          matlab.ui.container.Toolbar
        uitoggletool7       matlab.ui.container.toolbar.ToggleTool
        uitoggletool5       matlab.ui.container.toolbar.ToggleTool
        uitoggletool6       matlab.ui.container.toolbar.ToggleTool
        uitoggletool8       matlab.ui.container.toolbar.ToggleTool
        uipanel10           matlab.ui.container.Panel
        text_file_summary   matlab.ui.control.Label
        uipanel9            matlab.ui.container.Panel
        push_plot           matlab.ui.control.Button
        uipanel8            matlab.ui.container.Panel
        event_listbox       matlab.ui.control.ListBox
        wave_dropdown       matlab.ui.container.Panel
        wave_listbox        matlab.ui.control.ListBox
        panel_options       matlab.ui.container.Panel
        text8               matlab.ui.control.Label
        txt_start_x         matlab.ui.control.Label
        push_all            matlab.ui.control.StateButton
        push_auto           matlab.ui.control.StateButton
        txt_startx_unit     matlab.ui.control.Label
        txt_winsize_x_unit  matlab.ui.control.Label
        ed_y_min            matlab.ui.control.EditField
        ed_y_max            matlab.ui.control.EditField
        ed_winsize_x        matlab.ui.control.EditField
        ed_start_x          matlab.ui.control.EditField
        txt_winsize_y       matlab.ui.control.Label
        txt_winsize_x       matlab.ui.control.Label
        push_back           matlab.ui.control.Button
        push_next           matlab.ui.control.Button
        axes_1              matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function list_wave_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to list_wave (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function panel_event_SelectionChangeFcn(app, hObject, eventdata, handles)
            % hObject    handle to the selected object in panel_event
            % eventdata  structure with the following fields (see UIBUTTONGROUP)
            %	EventName: string 'SelectionChanged' (read only)
            %	OldValue: handle of the previously selected object or empty if none was selected
            %	NewValue: handle of the currently selected object
            % handles    structure with handles and user data (see GUIDATA)
            
            status0=get(handles.radio_enone,'Value');
            status1=get(handles.radio_extra,'Value');
            status2=get(handles.radio_integrated,'Value');
            status3=get(handles.radio_hb,'Value');
            
            
            if status0 == 1
                handles.prop.event='none';
            elseif status1 == 1
                handles.prop.event='extra';
            elseif status2 == 1
                handles.prop.event='integrated';
            elseif status3 == 1
                handles.prop.event='hb';
            end


            guidata(hObject, handles);
            pp_plot(app, handles);
            
            set(handles.push_auto,'Value',0);
            set(handles.push_all,'Value',1);
        end
        
        function panel_wave_SelectionChangeFcn(app, hObject, eventdata, handles)
            % hObject    handle to the selected object in panel_wave
            % eventdata  structure with the following fields (see UIBUTTONGROUP)
            %	EventName: string 'SelectionChanged' (read only)
            %	OldValue: handle of the previously selected object or empty if none was selected
            %	NewValue: handle of the currently selected object
            % handles    structure with handles and user data (see GUIDATA)


            status0=get(handles.radio_wnone,'Value');
            status1=get(handles.radio_scr,'Value');
            status2=get(handles.radio_ecg,'Value');
            status3=get(handles.radio_hr,'Value');
            status4=get(handles.radio_hp,'Value');
            status5=get(handles.radio_pupil,'Value');
            status6=get(handles.radio_emg,'Value');
            status7=get(handles.radio_resp,'Value');
            
            set(handles.push_auto,'Enable','on');
            set(handles.push_all,'Enable','on');
            
            if status0== 1
                handles.prop.wave='none';
                set(handles.radio_hb,'Enable','on');
                set(handles.push_auto,'Enable','off');
                set(handles.push_all,'Enable','off')
                set(handles.radio_integrated,'Enable','off')
            elseif status1 == 1
                handles.prop.wave='scr';
            elseif status2 == 1
                handles.prop.wave='ecg';
            elseif status3 == 1
                handles.prop.wave='hr';
            elseif status4==1
                handles.prop.wave='hp';
            elseif status5==1
                handles.prop.wave='pupil';
            elseif status6==1
                handles.prop.wave='emg';
            elseif status7==1
                handles.prop.resp='resp';
            end
            
            if ~(strcmp(handles.prop.wave,'ecg')) && strcmp(handles.prop.event,'hb')
                handles.prop.event='extra';
                set(handles.radio_extra,'Value',1)
            end
            
            if status0==0 ; set(handles.radio_integrated,'Enable','on') ; end
            
            guidata(hObject, handles);
            pp_plot(app, handles);
            
            set(handles.push_auto,'Value',0);
            set(handles.push_all,'Value',1);
        end
        
        function info = pp_plot(app, handles)
            % ---header----------------------------------------------------------------
            
            %       handles.name ... filename
            %       prop... struct with fields
            %               .wave (channel number)
            %               .event(channel number)
            %
            % ---Initialise------------------------------------------------------------
            marker=[];
            hbeat=[];
            events=[]; % any other marker channels
            wave=[];
            
            % get eventchan info
            if not(isempty(handles.prop.eventchans)) && not(handles.prop.eventchans(handles.prop.idevent)==0) &&  strcmp(handles.data{handles.prop.eventchans(handles.prop.idevent),1}.header.chantype,'marker')
                marker=handles.data{handles.prop.eventchans(handles.prop.idevent),1}.data;
                if get(handles.radio_extra,'Value')==1
                    handles.prop.event='extra';
                else
                    handles.prop.event='integrated';
                end
            elseif not(isempty(handles.prop.eventchans)) && not(handles.prop.eventchans(handles.prop.idevent)==0) && strcmp(handles.data{handles.prop.eventchans(handles.prop.idevent),1}.header.chantype,'hb')
                hbeat=handles.data{handles.prop.eventchans(handles.prop.idevent),1}.data;
                if get(handles.radio_extra,'Value')==1
                    handles.prop.event='extra';
                else
                    handles.prop.event='integrated';
                end
            elseif not(isempty(handles.prop.eventchans)) && not(handles.prop.eventchans(handles.prop.idevent)==0)
                events=handles.data{handles.prop.eventchans(handles.prop.idevent),1}.data;
                if get(handles.radio_extra,'Value')==1
                    handles.prop.event='extra';
                else
                    handles.prop.event='integrated';
                end
            end
            
            % get wave channel info
            if not(handles.prop.wavechans(handles.prop.idwave)==0)
                wave=handles.data{handles.prop.wavechans(handles.prop.idwave),1}.data;
                sr.wave=handles.data{handles.prop.wavechans(handles.prop.idwave),1}.header.sr;
            end
            
            %   plotting if no channel is selected
            % -------------------------------------------------------------------------
            if isempty(marker) && isempty(wave) && isempty(hbeat) && isempty(events)
                hold off
                x=1;
                y=1;
                plot(x);
                text(x,y,'   nothing to display - please select channel   ','FontSize',20,'BackgroundColor','k',...
                    'Color','w','HorizontalAlignment','Center','VerticalAlignment',...
                    'Middle')
            elseif not(isempty(marker)) || not(isempty(wave)) || not(isempty(hbeat)) || not(isempty(events))
            
                % ---prepare ecg-----------------------------------------------------------
                if not(isempty(handles.prop.wave))  % only if there is wave info
            
                    switch handles.prop.wave
                        case 'ecg'
                        case 'hr'
                        case 'hp'
                        case 'scr'
                        case 'pupil'
                        case 'emg'
                        case 'resp'
                    end
            
                end
            
                %   plotting if both event and wave channel are selected
                % -------------------------------------------------------------------------
                % plotting data against sr corrected time
                if not(isempty(wave)) % && (not(isempty(marker)) || not(isempty(hbeat)))
            
                    y=zeros(size(wave));
            
                    y=(0:1/sr.wave:(length(wave)/sr.wave));
                    y=y';
                    y=y(1:size(wave,1),1:size(wave,2));
            
                    y=y(1:size(wave,1),1);
                    % ---plot wave channel---------------------------------------------
            
                    plot(y, wave, 'Color', 'k');
            
                    % ---plot eventmarker--------------------------------------------
            
                    % set basline value for the event chanel
                    base(1)=min(wave)-.1*min(wave);
                    base(2)=min(wave)-(max(wave)-min(wave));
            
                    if not(isempty(hbeat))
            
                        hbeat=round(hbeat*sr.wave);
            
                        HBEAT = nan(size(wave));
            
                        if strcmp(handles.prop.event,'extra')
                            HBEAT(hbeat,1)=min(wave)-.5;
                        elseif strcmp(handles.prop.event,'integrated')
                            temp=wave(hbeat,1);
                            temp(isnan(temp)) = median(temp,'omitnan');
                            HBEAT(hbeat,1)=temp;
                        end
            
                        hold on ; h=stem(y,HBEAT,'ro');
                        hbase=get(h,'Baseline');
            
                        if strcmp(handles.prop.event,'extra')
                            set(hbase,'BaseValue',base(2),'Visible','off');
                        elseif strcmp(handles.prop.event,'integrated')
                            set(hbase,'BaseValue',base(1),'Visible','off');
                        end
            
                    elseif not(isempty(marker))
            
                        marker=round(marker*sr.wave);
                        if marker(1,1)==0
                            marker(1,1)=1;
                        end
                        marker=marker(marker~=0);
                        MARKER=nan(size(wave));
            
            
                        if strcmp(handles.prop.event,'extra')
                            MARKER(marker,1)=min(wave)-.5;
                        elseif strcmp(handles.prop.event,'integrated')
                            temp=wave(marker,1);
                            temp(isnan(temp)) = median(temp,'omitnan');
                            MARKER(marker,1)=temp;
                        end
            
                        hold on ; h=stem(y,MARKER,'ro');
                        hbase=get(h,'Baseline');
            
                        if strcmp(handles.prop.event,'extra')
                            set(hbase,'BaseValue',base(2),'Visible','off');
                        elseif strcmp(handles.prop.event,'integrated')
                            set(hbase,'BaseValue',base(1),'Visible','off');
                        end
                    elseif not(isempty(events))
            
                        events=round(events*sr.wave);
            
                        EVENTS = nan(size(wave));
            
                        if strcmp(handles.prop.event,'extra')
                            EVENTS(events,1)=min(wave)-.5;
                        elseif strcmp(handles.prop.event,'integrated')
                            temp=wave(events,1);
                            temp(isnan(temp)) = median(temp,'omitnan');
                            EVENTS(events,1)=temp;
                        end
            
                        hold on ; h=stem(y,EVENTS,'r');
                        hbase=get(h,'Baseline');
            
                        if strcmp(handles.prop.event,'extra')
                            set(hbase,'BaseValue',base(2),'Visible','off');
                        elseif strcmp(handles.prop.event,'integrated')
                            set(hbase,'BaseValue',base(1),'Visible','off');
                        end
                    end
            
            
                    wv_chanid = handles.prop.wavechans(handles.prop.idwave);
                    unit = deblank(handles.data{wv_chanid}.header.units);
                    if strcmp(handles.prop.wave,'ecg')
                        ylabel([' Amplitude [',unit,'] '],'Fontsize',14);
                    elseif strcmp(handles.prop.wave,'scr')
                        ylabel([' Amplitude [',unit,'] '],'Fontsize',14)
                    elseif strcmp(handles.prop.wave,'emg')
                        ylabel([' Amplitude [',unit,'] '],'Fontsize',14)
                    elseif strcmp(handles.prop.wave,'hp')
                        ylabel(' Interpolated IBI [ms] ','Fontsize',14)
                    elseif strcmp(handles.prop.wave,'pupil')
                        ylabel(' size in arbitrary units ','Fontsize',14)
                    elseif contains(handles.prop.wave,'pupil')
                        ylabel([' Pupil size [',unit,'] '],'Fontsize',14)
                    elseif contains(handles.prop.wave,'gaze') && contains(handles.prop.wave,'x')
                        ylabel([' Gaze x coordinate [',unit,'] '],'Fontsize',14)
                    elseif contains(handles.prop.wave,'gaze') && contains(handles.prop.wave,'y')
                        ylabel([' Gaze y coordinate [',unit,'] '],'Fontsize',14)
                    else ylabel([' unknown unit [',unit,'] '],'Fontsize',14)
                    end
            
                    xlabel(' Time in seconds [s] ','Fontsize',16);
            
                    if not(isempty(marker))
                        legend(handles.prop.wave,'marker')
                    elseif not(isempty(hbeat))
                        legend(handles.prop.wave,'heartbeats')
                    elseif not(isempty(events))
                        legend(handles.prop.wave,[handles.event_listbox.String{handles.prop.idevent},' events'])
                    elseif not(strcmp(handles.prop.event,'none'))
                        legend(handles.prop.wave,'unknown events')
                    else
                        legend(handles.prop.wave)
                    end
            
                    %   plotting if only event channel is selected
                    % -------------------------------------------------------------------------
            
                elseif isempty(wave) && (not(isempty(hbeat)) || not(isempty(marker)) || not(isempty(events)))
                    if not(isempty(hbeat))
                        MARKER=diff(hbeat);
                        plot(MARKER,'ro')
                        ylabel('duration of ibi [s]','Fontsize',14)
                    elseif not(isempty(marker))
                        MARKER=diff(marker);
                        stem(MARKER,'r')
                        ylabel('inter-marker duration [s]','Fontsize',14)
                    elseif not(isempty(events))
                        MARKER=diff(events);
                        plot(MARKER,'ro')
                        ylabel('inter-event duration [s]','Fontsize',14)
                    else
                        MARKER=[];
                    end
            
                    xlabel('Time in seconds [s] ','Fontsize',16);
                    if not(isempty(marker))
                        legend('marker')
                    elseif not(isempty(hbeat))
                        legend('heartbeats')
                    elseif not(isempty(events))
                        legend([handles.event_listbox.String{handles.prop.idevent},' events'])
                    else
                        legend('unknown events')
                    end
                end
            end
            % -------------------------------------------------------------------------
            title(sprintf(' %s ',regexprep(handles.name, '([\\_])' ,'\\$1')),'Fontsize',18);
            set(handles.axes_1,'XGrid','on')
            hold off
            % -------------------------------------------------------------------------
            
            x=get(handles.axes_1,'xlim');
            y=get(handles.axes_1,'ylim');
            x(2)=x(2)-x(1);
            set(handles.ed_y_min,'String',num2str(y(1)))
            set(handles.ed_y_max,'String',num2str(y(2)))
            set(handles.ed_start_x,'String',num2str(x(1)))
            set(handles.ed_winsize_x,'String',num2str(x(2)))
        end
        
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool7, app.uitoggletool5, app.uitoggletool6, app.uitoggletool8];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.pspm_display, 'off')
            rotate3d(app.pspm_display, 'off');
            pan(app.pspm_display, 'off');
            zoom(app.pspm_display,'off');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function pspm_display_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   unrecognized PropertyName/PropertyValue pairs from the
            %            command line (see VARARGIN)
            
            % ---initialise------------------------------------------------------------
            
            global settings;
            if isempty(settings), pspm_init; end;
            
            % load chantypes from settings variable
            
            j=1 ; l=1;
            for k=1:length(settings.chantypes)
                if strcmp(settings.chantypes(k).data,'wave')
                    handles.prop.setwave{j}=settings.chantypes(k).type;
                    j=j+1;
                elseif strcmp(settings.chantypes(k).data,'events')
                    handles.prop.setevent{l}=settings.chantypes(k).type;
                    l=l+1;
                end
            end
            
            handles.prop.wave=[];
            handles.prop.event=[];
            handles.prop.wavechans=[];
            handles.prop.eventchans=[];
            handles.prop.axis=[];
            handles.name=[];
            set(hObject,'Resize','on');
            % -------------------------------------------------------------------------
            
            if(numel(varargin)) == 1
                if iscell(varargin{1,1})
                    filename=varargin{1,1}{1};
                else
                    filename=varargin{1,1};
                end
                [~, info, handles.data, filestruct]=pspm_load_data(filename,0);
            
                handles.name=filename;
            
                % handles.text_file_summary = filename;
                text_file_summary = ['Data source: ', filename, newline, newline, ...
                    'Duration: ', num2str(info.duration), newline,...
                    'Import date: ', info.importdate, newline, ...
                    'Position of marker: ', num2str(filestruct.posofmarker)];
                set(handles.text_file_summary, 'String', text_file_summary);
            
                guidata(hObject, handles);
            
                % ---add text to wave listbox--------------------------------------
            
                listitems{1,1}='none';
                handles.prop.wavechans(1)=0;
                j=2;
                for k=1:length(handles.data)
                    if any(strcmp(handles.data{k,1}.header.chantype,handles.prop.setwave))
                        listitems{j,1}=handles.data{k,1}.header.chantype;
                        handles.prop.wavechans(j)=k;
                        j=j+1;
                    end
                end
            
                set(handles.wave_listbox,'String',listitems);
                clear listitems
            
                % ---add text to event listbox & activate additional options-------
                listitems{1,1}='none';
                handles.prop.eventchans(1)=0;
                j=2;
                for k=1:length(handles.data)
                    if any(strcmp(handles.data{k,1}.header.chantype,handles.prop.setevent))
                        listitems{j,1}=handles.data{k,1}.header.chantype;
                        handles.prop.eventchans(j)=k;
                        j=j+1;
                        set(handles.radio_int,'Enable','on');
                        set(handles.radio_extra,'Enable','on');
                    end
                end
            
                set(handles.event_listbox,'String',listitems);
                set(handles.push_auto,'Value',0);
                set(handles.push_all,'Value',1);
            
            elseif numel(varargin)>1 ; warning('too many input arguments. Inputs 2:end ignored. ') ;
            end
            
            % Choose default command line output for pspm_display
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: ed_start_x
        function ed_start_x_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_start_x (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ed_start_x as text
            %        str2double(get(hObject,'String')) returns contents of ed_start_x as a double
            x1=str2num(get(handles.ed_start_x,'String'));
            x2=str2num(get(handles.ed_winsize_x,'String'))+x1;
            
            y1=str2num(get(handles.ed_y_min,'String'));
            y2=str2num(get(handles.ed_y_max,'String'));
            
            if y1 >= y2
                warning(sprintf(' Ymin ( current input: %d ) must be smaller than Ymax ( current input: %d ) ! ',y1,y2));
            else
                axis([x1 x2 y1 y2])
            end
            set(handles.push_auto,'Value',0);
            set(handles.push_all,'Value',0);
        end

        % Value changed function: ed_winsize_x
        function ed_winsize_x_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_winsize_x (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ed_winsize_x as text
            %        str2double(get(hObject,'String')) returns contents of ed_winsize_x as a double
            x1=str2num(get(handles.ed_start_x,'String'));
            
            x2=str2num(get(handles.ed_winsize_x,'String'))+x1;
            
            y1=str2num(get(handles.ed_y_min,'String'));
            y2=str2num(get(handles.ed_y_max,'String'));
            
            if y1 >= y2
                warning(sprintf(' Ymin ( current input: %d ) must be smaller than Ymax ( current input: %d ) ! ',y1,y2));
            else
                axis([x1 x2 y1 y2])
            end
            
            set(handles.push_auto,'Value',0);
            set(handles.push_all,'Value',0);
        end

        % Value changed function: ed_y_max
        function ed_y_max_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_y_max (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ed_y_max as text
            %        str2double(get(hObject,'String')) returns contents of ed_y_max as a double
            
            x1=str2num(get(handles.ed_start_x,'String'));
            x2=str2num(get(handles.ed_winsize_x,'String'))+x1;
            
            y1=str2num(get(handles.ed_y_min,'String'));
            y2=str2num(get(handles.ed_y_max,'String'));
            
            if y1 >= y2
                warning(sprintf(' Ymin ( current input: %d ) must be smaller than Ymax ( current input: %d ) ! ',y1,y2));
            else
                axis([x1 x2 y1 y2])
            end
            
            set(handles.push_auto,'Value',0);
            set(handles.push_all,'Value',0);
        end

        % Value changed function: ed_y_min
        function ed_y_min_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ed_y_min (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ed_y_min as text
            %        str2double(get(hObject,'String')) returns contents of ed_y_min as a double
            x1=str2num(get(handles.ed_start_x,'String'));
            x2=str2num(get(handles.ed_winsize_x,'String'))+x1;
            
            y1=str2num(get(handles.ed_y_min,'String'));
            y2=str2num(get(handles.ed_y_max,'String'));
            
            if y1 >= y2
                warning(sprintf(' Ymin ( current input: %d ) must be smaller than Ymax ( current input: %d ) ! ',y1,y2));
            else
                axis([x1 x2 y1 y2])
            end
            
            set(handles.push_auto,'Value',0);
            set(handles.push_all,'Value',0);
        end

        % Menu selected function: exit
        function exit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to exit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(gcbf);
        end

        % Menu selected function: load
        function load_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to load (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % -------------------------------------------------------------------------
            wd=cd;
            typ='mat';
            sel=[];
            mesg='select pspm_datafiles to display';
            
            [filename,sts]=spm_select(1,typ,mesg,sel,wd);
            
            if not(sts==0)
            
                [foo, foo, handles.data, foo]=pspm_load_data(filename,0);
            
                handles.name=filename;
                guidata(hObject, handles);
            
                % ---set wave and event channel value to none ---------------------
                handles.prop.wave='none';
                set(handles.wave_listbox,'Value',1)
            
                handles.prop.event='none';
                set(handles.event_listbox,'Value',1)
            
                % ---add text to wave listbox--------------------------------------
            
                listitems{1,1}='none';
                handles.prop.wavechans(1)=0;
                j=2;
                for k=1:length(handles.data)
                    if any(strcmp(handles.data{k,1}.header.chantype,handles.prop.setwave))
                        listitems{j,1}=handles.data{k,1}.header.chantype;
                        handles.prop.wavechans(j)=k;
                        j=j+1;
                    end
                end
            
                set(handles.wave_listbox,'String',listitems);
                clear listitems
            
                % ---add text to event listbox & activate additional options-------
                listitems{1,1}='none';
                handles.prop.eventchans(1)=0;
                j=2;
                for k=1:length(handles.data)
                    if any(strcmp(handles.data{k,1}.header.chantype,handles.prop.setevent))
                        listitems{j,1}=handles.data{k,1}.header.chantype;
                        handles.prop.eventchans(j)=k;
                        j=j+1;
                        set(handles.radio_int,'Enable','on');
                        set(handles.radio_extra,'Enable','on');
                    end
                end
            
                set(handles.event_listbox,'String',listitems);
                set(handles.push_auto,'Value',0);
                set(handles.push_all,'Value',1);
            
            elseif numel(varargin)>1 ; warning('too many input arguments. Inputs 2:end ignored. ') ;
            end
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: push_all
        function push_all_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_all (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            axis('auto');
            
            y=get(handles.axes_1,'ylim');
            x=get(handles.axes_1,'xlim');
            
            set(handles.ed_start_x,'String',num2str(x(1)));
            set(handles.ed_winsize_x,'String',num2str(x(2)-x(1)));
            set(handles.ed_y_min,'String',num2str(y(1)));
            set(handles.ed_y_max,'String',num2str(y(2)));
            set(handles.push_auto,'Value',0);
        end

        % Value changed function: push_auto
        function push_auto_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_auto (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            axis('auto')
            y=get(handles.axes_1,'ylim');
            x=str2num(get(handles.ed_start_x,'String'));
            
            x(2)=x(1)+15;
            
            
            axis([x(1) x(2) y(1) y(2)])
            
            set(handles.ed_start_x,'String',num2str(x(1)));
            set(handles.ed_winsize_x,'String',num2str(x(2)-x(1)));
            set(handles.ed_y_min,'String',num2str(y(1)));
            set(handles.ed_y_max,'String',num2str(y(2)));
            set(handles.push_all,'Value',0);
        end

        % Button pushed function: push_back
        function push_back_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_back (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            x1=str2num(get(handles.ed_start_x,'String'));
            x2=str2num(get(handles.ed_winsize_x,'String'));
            
            x1=x1-x2; x2=x1+x2;
            
            set(handles.ed_start_x,'String',num2str(x1));
            
            y=get(handles.axes_1,'YLim');
            
            axis([x1 x2 y(1) y(2)])
            
            set(handles.ed_start_x,'String',num2str(x1));
            set(handles.ed_y_min,'String',num2str(y(1)));
            set(handles.ed_y_max,'String',num2str(y(2)));
            
            set(handles.push_all,'Value',0);
        end

        % Button pushed function: push_next
        function push_next_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_next (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            x1=str2num(get(handles.ed_start_x,'String'));
            x2=str2num(get(handles.ed_winsize_x,'String'));
            
            y=get(handles.axes_1,'YLim');
            
            x1=x1+x2; x2=x1+x2;
            
            axis([x1 x2 y(1) y(2)])
            
            set(handles.ed_start_x,'String',num2str(x1));
            set(handles.ed_y_min,'String',num2str(y(1)));
            set(handles.ed_y_max,'String',num2str(y(2)));
            
            set(handles.push_all,'Value',0);
        end

        % Button pushed function: push_plot
        function push_plot_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_plot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % ---get selected wave channel---------------------------------------------
            handles.prop.idwave=get(handles.wave_listbox,'Value');
            handles.prop.wave=get(handles.wave_listbox,'String');
            handles.prop.wave=handles.prop.wave{handles.prop.idwave,1};
            
            % ---get selected event channel--------------------------------------------
            handles.prop.idevent=get(handles.event_listbox,'Value');
            handles.prop.event=get(handles.event_listbox,'String');
            handles.prop.event=handles.prop.event{handles.prop.idevent,1};
            % ---deactivate marker buttons if necessary--------------------------------
            
            if handles.prop.idevent==1 || handles.prop.idwave==1
                set(handles.radio_int,'Enable','Off');
                set(handles.radio_extra,'Enable','Off');
            else
                set(handles.radio_int,'Enable','On');
                set(handles.radio_extra,'Enable','On');
            end
            
            
            % -------------------------------------------------------------------------
            % Update handles structure
            guidata(hObject, handles);
            % -------------------------------------------------------------------------
            pp_plot(app, handles);
        end

        % Menu selected function: saveas
        function saveas_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to saveas (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            y=get(handles.axes_1,'ylim');
            x=get(handles.axes_1,'xlim');
            
            [filename,pathname]=uiputfile({'*.jpg';'*.tif';'*.png';'*.gif';'*.bmp'},'Save Image');
            
            screen_size = get(0, 'ScreenSize');
            savename=sprintf('%s%s',pathname,filename);
            q=figure('Visible','Off');
            set(q, 'Position', [0 0 screen_size(3) screen_size(4) ] );
            pp_plot(app, handles);
            axis([x(1) x(2) y(1) y(2)]);
            
            % save the image as PNG
            saveas(q,savename);
            %imwrite(q,savename);
            close(q)
        end

        % Clicked callback: uitoggletool8
        function uitoggletool8_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable data tips based on the
            % tool's current state.
            state = app.uitoggletool8.State;
            datacursormode(app.pspm_display, char(state));
        end

        % Clicked callback: uitoggletool6
        function uitoggletool6_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.uitoggletool6.State;
            zoomModeObject = zoom(app.pspm_display);
            if state
                zoomModeObject.Direction = 'out';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Clicked callback: uitoggletool5
        function uitoggletool5_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.uitoggletool5.State;
            zoomModeObject = zoom(app.pspm_display);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Clicked callback: uitoggletool7
        function uitoggletool7_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable pan based on the
            % tool's current state.
            state = app.uitoggletool7.State;
            pan(app.pspm_display, char(state));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.pspm_display = uifigure('Visible', 'off');
            app.pspm_display.Name = 'pspm_display';
            app.pspm_display.Tag = 'pspm_display';
            app.file = uimenu(app.pspm_display);
            app.file.Text = 'File';
            app.file.Tag = 'file';
            app.load = uimenu(app.file);
            app.load.MenuSelectedFcn = createCallbackFcn(app, @load_Callback, true);
            app.load.Accelerator = 'O';
            app.load.Text = '... open';
            app.load.Tag = 'load';
            app.saveas = uimenu(app.file);
            app.saveas.MenuSelectedFcn = createCallbackFcn(app, @saveas_Callback, true);
            app.saveas.Accelerator = 'S';
            app.saveas.Text = '... save as';
            app.saveas.Tag = 'saveas';
            app.exit = uimenu(app.file);
            app.exit.MenuSelectedFcn = createCallbackFcn(app, @exit_Callback, true);
            app.exit.Separator = 'on';
            app.exit.Accelerator = 'W';
            app.exit.Text = 'exit';
            app.exit.Tag = 'exit';
            app.uitoolbar2 = uitoolbar(app.pspm_display);
            app.uitoolbar2.Tag = 'uitoolbar2';
            app.uitoggletool7 = uitoggletool(app.uitoolbar2);
            app.uitoggletool7.Tag = 'uitoggletool7';
            app.uitoggletool7.Tooltip = 'Pan';
            app.uitoggletool7.ClickedCallback = createCallbackFcn(app, @uitoggletool7_ClickedCallback, true);
            app.uitoggletool7.Icon = 'uitoggletool7_image.png';
            app.uitoggletool5 = uitoggletool(app.uitoolbar2);
            app.uitoggletool5.Tag = 'uitoggletool5';
            app.uitoggletool5.Tooltip = 'Zoom In';
            app.uitoggletool5.ClickedCallback = createCallbackFcn(app, @uitoggletool5_ClickedCallback, true);
            app.uitoggletool5.Icon = 'uitoggletool5_image.png';
            app.uitoggletool6 = uitoggletool(app.uitoolbar2);
            app.uitoggletool6.Tag = 'uitoggletool6';
            app.uitoggletool6.Tooltip = 'Zoom Out';
            app.uitoggletool6.ClickedCallback = createCallbackFcn(app, @uitoggletool6_ClickedCallback, true);
            app.uitoggletool6.Icon = 'uitoggletool6_image.png';
            app.uitoggletool8 = uitoggletool(app.uitoolbar2);
            app.uitoggletool8.Tag = 'uitoggletool8';
            app.uitoggletool8.Tooltip = 'Data Cursor';
            app.uitoggletool8.ClickedCallback = createCallbackFcn(app, @uitoggletool8_ClickedCallback, true);
            app.uitoggletool8.Icon = 'uitoggletool8_image.png';
            app.axes_1 = uiaxes(app.pspm_display);
            app.axes_1.Tag = 'axes_1';
            app.push_next = uibutton(app.pspm_display, 'push');
            app.push_next.ButtonPushedFcn = createCallbackFcn(app, @push_next_Callback, true);
            app.push_next.Tag = 'push_next';
            app.push_next.Tooltip = 'next';
            app.push_next.Text = '>>';
            app.push_back = uibutton(app.pspm_display, 'push');
            app.push_back.ButtonPushedFcn = createCallbackFcn(app, @push_back_Callback, true);
            app.push_back.Tag = 'push_back';
            app.push_back.Tooltip = 'back';
            app.push_back.Text = '<<';
            app.panel_options = uipanel(app.pspm_display);
            app.panel_options.Title = 'Display Options';
            app.panel_options.Tag = 'panel_options';
            app.txt_winsize_x = uilabel(app.panel_options);
            app.txt_winsize_x.Tag = 'txt_winsize_x';
            app.txt_winsize_x.Text = 'time window';
            app.txt_winsize_y = uilabel(app.panel_options);
            app.txt_winsize_y.Tag = 'txt_winsize_y';
            app.txt_winsize_y.Text = 'y min';
            app.ed_start_x = uieditfield(app.panel_options, 'text');
            app.ed_start_x.ValueChangedFcn = createCallbackFcn(app, @ed_start_x_Callback, true);
            app.ed_start_x.Tag = 'ed_start_x';
            app.ed_start_x.Tooltip = 'set the x starting point';
            app.ed_start_x.Value = '0';
            app.ed_winsize_x = uieditfield(app.panel_options, 'text');
            app.ed_winsize_x.ValueChangedFcn = createCallbackFcn(app, @ed_winsize_x_Callback, true);
            app.ed_winsize_x.Tag = 'ed_winsize_x';
            app.ed_winsize_x.Tooltip = 'define size of the x window';
            app.ed_winsize_x.Value = '22';
            app.ed_y_max = uieditfield(app.panel_options, 'text');
            app.ed_y_max.ValueChangedFcn = createCallbackFcn(app, @ed_y_max_Callback, true);
            app.ed_y_max.Tag = 'ed_y_max';
            app.ed_y_max.Tooltip = 'define size of the y window';
            app.ed_y_max.Value = '3';
            app.ed_y_min = uieditfield(app.panel_options, 'text');
            app.ed_y_min.ValueChangedFcn = createCallbackFcn(app, @ed_y_min_Callback, true);
            app.ed_y_min.Tag = 'ed_y_min';
            app.ed_y_min.Tooltip = 'define size of the y window';
            app.ed_y_min.Value = '3';
            app.txt_winsize_x_unit = uilabel(app.panel_options);
            app.txt_winsize_x_unit.Tag = 'txt_winsize_x_unit';
            app.txt_winsize_x_unit.Text = 's';
            app.txt_startx_unit = uilabel(app.panel_options);
            app.txt_startx_unit.Tag = 'txt_startx_unit';
            app.txt_startx_unit.Text = 's';
            app.push_auto = uibutton(app.panel_options, 'state');
            app.push_auto.ValueChangedFcn = createCallbackFcn(app, @push_auto_Callback, true);
            app.push_auto.Tag = 'push_auto';
            app.push_auto.Tooltip = 'set automatic values for zoom';
            app.push_auto.Text = 'autoscale';
            app.push_all = uibutton(app.panel_options, 'state');
            app.push_all.ValueChangedFcn = createCallbackFcn(app, @push_all_Callback, true);
            app.push_all.Tag = 'push_all';
            app.push_all.Tooltip = 'no zoom';
            app.push_all.Text = 'all';
            app.txt_start_x = uilabel(app.panel_options);
            app.txt_start_x.Tag = 'txt_start_x';
            app.txt_start_x.Text = 'starting point';
            app.text8 = uilabel(app.panel_options);
            app.text8.Tag = 'text8';
            app.text8.Text = 'y max';
            app.wave_dropdown = uipanel(app.pspm_display);
            app.wave_dropdown.Title = 'Wave Channels';
            app.wave_dropdown.Tag = 'wave_dropdown';
            app.wave_listbox = uilistbox(app.wave_dropdown);
            app.wave_listbox.Items = {'none'};
            app.wave_listbox.Tag = 'wave_listbox';
            app.wave_listbox.Value = 'none';
            app.uipanel8 = uipanel(app.pspm_display);
            app.uipanel8.Title = 'Event Channels';
            app.uipanel8.Tag = 'uipanel8';
            app.event_listbox = uilistbox(app.uipanel8);
            app.event_listbox.Items = {'none'};
            app.event_listbox.Tag = 'event_listbox';
            app.event_listbox.Value = 'none';
            app.push_plot = uibutton(app.pspm_display, 'push');
            app.push_plot.ButtonPushedFcn = createCallbackFcn(app, @push_plot_Callback, true);
            app.push_plot.Tag = 'push_plot';
            app.push_plot.Text = 'Plot';
            app.uipanel9 = uipanel(app.pspm_display);
            app.uipanel9.Title = 'Event Options';
            app.uipanel9.Tag = 'uipanel9';
            app.uipanel10 = uipanel(app.pspm_display);
            app.uipanel10.Title = 'Summary';
            app.uipanel10.Tag = 'uipanel10';
            app.text_file_summary = uilabel(app.uipanel10);
            app.text_file_summary.Tag = 'text_file_summary';
            app.text_file_summary.Text = 'Static Text';
            % Show the figure after all components are created
            app.pspm_display.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pspm_display_App_1(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.pspm_display)

                % Execute the startup function
                runStartupFcn(app, @(app)pspm_display_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.pspm_display)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.pspm_display)
        end
    end
end